classdef CalibrationTools < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CalibrationToolsUIFigure  matlab.ui.Figure
        Panel                     matlab.ui.container.Panel
        R2EditField               matlab.ui.control.NumericEditField
        R2Label_2                 matlab.ui.control.Label
        CorrectedTable            matlab.ui.control.Table
        CorrectedLabel            matlab.ui.control.Label
        UncorrectedLabel          matlab.ui.control.Label
        R2EditField_2             matlab.ui.control.NumericEditField
        R2Label                   matlab.ui.control.Label
        UncorrectedTable          matlab.ui.control.Table
        SaveCalibrationButton     matlab.ui.control.Button
        FilmLabel_2               matlab.ui.control.Label
        CalibLabel_2              matlab.ui.control.Label
        MeanDoseEditField         matlab.ui.control.NumericEditField
        MeanDoseLabel             matlab.ui.control.Label
        ModeDoseEditField         matlab.ui.control.NumericEditField
        ModeDoseLabel             matlab.ui.control.Label
        CalibType2Button          matlab.ui.control.Button
        Label_2                   matlab.ui.control.Label
        CalibrationFilmTestPanel  matlab.ui.container.Panel
        FilterButton              matlab.ui.control.Button
        FilterEditField           matlab.ui.control.NumericEditField
        MeasureButton             matlab.ui.control.Button
        Convert2DoseButton        matlab.ui.control.Button
        LoadFilmButton            matlab.ui.control.Button
        ChannelDropDown           matlab.ui.control.DropDown
        ChannelDropDownLabel      matlab.ui.control.Label
        FitButton                 matlab.ui.control.Button
        DegreeSpinner             matlab.ui.control.Spinner
        DegreeSpinnerLabel        matlab.ui.control.Label
        UITable                   matlab.ui.control.Table
        Label                     matlab.ui.control.Label
        CalibType1Button          matlab.ui.control.Button
        UIAxes_4                  matlab.ui.control.UIAxes
        UIAxes_3                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Data;
        Film;
        fit_initial; fit_final;fittype;
        Converted_Dose;
        mainapp;
        MainappTable;
        Path;
    end
    
    methods (Access = private)

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, Table)
                app.MainappTable = Table;                
                app.mainapp = mainapp;

                if isempty(app.MainappTable)
                    folderPath = fullfile(fileparts(mfilename('fullpath')));
                    addpath(folderPath);
                     % Read the table
                    app.UITable.Data = readmatrix('DefaultCalibration.txt');
                else
                    app.UITable.Data = app.MainappTable;
                end                
                
                app.Label.Text = 'Default Calibration';
        end

        % Button pushed function: CalibType1Button
        function CalibType1ButtonPushed(app, event)
            [filename, path] = uigetfile("*/*.txt", "please select the file");
            file = fullfile(path, filename);
            app.Label.Text = filename;
 
            % Read the table
            Table1 = readmatrix(file);
            Table1 = Table1(1:end-1, 2:end);
            app.UITable.Data = Table1;
        end

        % Button pushed function: FitButton
        function FitButtonPushed(app, event)
            data_cell = app.UITable.Data;


            %Renaming the table column name
            degree = app.DegreeSpinner.Value;
            column_names = cell(1, degree);
            for i = 0 : (degree -1)
                column_names{i+1} = ['X^', num2str(degree - i)]; 
            end      
            column_names{end + 1} = 'C';
            app.UncorrectedTable.ColumnName = column_names;
            app.CorrectedTable.ColumnName = column_names;

            %Reading the Data from the table
            Dose = data_cell(:, 1);
            Red = data_cell(:, 2);
            Green = data_cell(:, 3);
            Blue = data_cell(:, 4);


            %Normalizing the channels and correcting for the Blue channel
            Red_norm = -log10(max(Red, 1) ./65535);
            Green_norm = -log10(max(Green, 1) ./65535);
            Blue_norm = -log10(max(Blue, 1) ./65535);
            Red_corr = Red_norm ./Blue_norm;
            Green_corr = Green_norm ./Blue_norm;
            app.fittype = app.ChannelDropDown.Value;

            %Fitting the dose
            switch app.fittype
                case 'Red'
                    %For fitting
                    app.fit_initial = polyfit(Red_corr, Red_norm, app.DegreeSpinner.Value);                       
                    val = polyval(app.fit_initial, Red_corr);
                    app.fit_final = polyfit(val, Dose, app.DegreeSpinner.Value);
                    
                    y_pred1 = polyval(app.fit_initial, Red_corr);
                    uncorr_R2 = 1 - sum((Red_norm - y_pred1).^2) / sum((Red_norm- mean(Red_norm)).^2);
        
                    y_pred = polyval(app.fit_final, val);
                    corr_R2 = 1 - sum((Dose - y_pred).^2) / sum((Dose - mean(Dose)).^2);

                    %For plotting
                    plot(app.UIAxes_4, Red_corr, Red_norm, 'o');
                    hold(app.UIAxes_4, 'on');
                    plot(app.UIAxes_4, Red_corr, val, '-');
                    hold(app.UIAxes_4, 'off');
                                       

                case 'Green'
                     app.fit_initial = polyfit(Green_corr, Green_norm, app.DegreeSpinner.Value);                       
                     val = polyval(app.fit_initial, Green_corr);
                     app.fit_final = polyfit(val, Dose, app.DegreeSpinner.Value);

                    y_pred1 = polyval(app.fit_initial, Green_corr);
                    uncorr_R2 = 1 - sum((Green_norm - y_pred1).^2) / sum((Green_norm- mean(Green_norm)).^2);
        
                    y_pred = polyval(app.fit_final, val);
                    corr_R2 = 1 - sum((Dose - y_pred).^2) / sum((Dose - mean(Dose)).^2);

                    %For plotting
                    plot(app.UIAxes_4, Green_corr, Green_norm, 'o');
                    hold(app.UIAxes_4, 'on');
                    plot(app.UIAxes_4, Green_corr, val, '-');
                    hold(app.UIAxes_4, 'off');
            end

            %Updating the UI components with the calculated values            
            app.R2EditField_2.Value = uncorr_R2;
            app.UncorrectedTable.Data = app.fit_initial;
            app.CorrectedTable.Data = app.fit_final;
            app.R2EditField.Value = corr_R2;

            %passing the values to the main app
            app.mainapp.updateFits(app.fit_initial, app.fit_final,app.fittype);

        end

        % Button pushed function: LoadFilmButton
        function LoadFilmButtonPushed(app, event)
            [filename, path] = uigetfile({"*.TIF"; "*.TIFF"; "*.tiff"}, 'Please select the tiff file');
            file = fullfile(path, filename);
            app.Film =  imread(file);
            app.Label_2.Text = file;
        end

        % Button pushed function: Convert2DoseButton
        function Convert2DoseButtonPushed(app, event)
             Red_channel = double(app.Film(:,:,1));
             Red_norm= -log10(max(Red_channel, 1) ./ 65535);
             Green_channel = double(app.Film(:,:,2));
             Green_norm = -log10(max(Green_channel, 1) ./ 65535);
             Blue_channel = double(app.Film(:,:,3));
             Blue_norm = -log10(max(Blue_channel, 1) ./ 65535);

            switch app.ChannelDropDown.Value
                case 'Red'
                   corr = Red_norm ./Blue_norm;
                case 'Green'
                   corr = Green_norm ./Blue_norm;
            end

            %Fitting the data
            channel_corr = polyval(app.fit_initial,  corr); 
            app.Converted_Dose = polyval(app.fit_final,  channel_corr);
    
            figure;
            imshow(app.Converted_Dose, []);
            colormap("jet");
            
            FilterButtonPushed(app);            
        end

        % Button pushed function: MeasureButton
        function MeasureButtonPushed(app, event)
            imshow(app.Converted_Dose, []);
            colormap("jet");
            ROI = drawrectangle;

           X1 = round(ROI.Position(1));
           Y1 = round(ROI.Position(2));
           X2 = round(X1 + ROI.Position(3) - 1);
           Y2 = round(Y1 + ROI.Position(4) - 1);

           % Extracting color channels from rotated_image, not X
           ROI_mat = app.Converted_Dose(Y1:Y2, X1:X2);
           Dose = mean(ROI_mat(:));
           Mode_dose = mode(ROI_mat(:));
           histogram(app.UIAxes_3, ROI_mat);

           app.MeanDoseEditField.Value = Dose;
           app.ModeDoseEditField.Value = Mode_dose;
        end

        % Button pushed function: FilterButton
        function FilterButtonPushed(app, event)
            filter_value = app.FilterEditField.Value;
            app.Converted_Dose(isnan(app.Converted_Dose) | isinf(app.Converted_Dose) | abs(app.Converted_Dose) > filter_value) = 1 ;
            imshow(app.Converted_Dose, []);
            colormap("jet");
        end

        % Button pushed function: CalibType2Button
        function CalibType2ButtonPushed(app, event)
            [filename, path] = uigetfile({'*.txt'}, 'Please select the image file');
            file = fullfile(path, filename);
            % Read the table
            app.UITable.Data = readmatrix(file);        
            app.Label.Text = file;
        end

        % Button pushed function: SaveCalibrationButton
        function SaveCalibrationButtonPushed(app, event)
             dose_table = app.UITable.Data;
            
            % Extracting individual columns
            dose = dose_table(:,1);
            Red_ch = dose_table(:,2);
            Green_ch = dose_table(:,3);
            Blue_ch = dose_table(:,4);
            
            % Prepare the full data to save, including headers
            data_to_save = [dose, Red_ch, Green_ch, Blue_ch];
            
            % Get file name and path for saving
            [filename, pathname] = uiputfile({'*.txt'}, 'Please select', app.Path);
            if filename ~= 0
                file_name = fullfile(pathname, filename);
                app.Path = pathname; % Update app's path property
            
                % Write the data as a tab-delimited text file
                writematrix(data_to_save, file_name, 'Delimiter', 'tab');             
            else
                disp('File saving was cancelled.');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.CalibrationToolsUIFigure = uifigure('Visible', 'off');
            app.CalibrationToolsUIFigure.Name = 'Calibration Tools';
            app.CalibrationToolsUIFigure.Icon = fullfile(pathToMLAPP, 'dlgapps_resources', 'icons8-wrench-100.png');
            app.UIAxes_3 = uiaxes(app.CalibrationToolsUIFigure);
            app.UIAxes_4 = uiaxes(app.CalibrationToolsUIFigure);
            app.CalibType1Button = uibutton(app.CalibrationToolsUIFigure, 'push');
            app.CalibType1Button.ButtonPushedFcn = createCallbackFcn(app, @CalibType1ButtonPushed, true);
            app.CalibType1Button.Text = 'Calib(Type1)';
            app.CalibType1Button.Icon = fullfile(pathToMLAPP, 'resources', 'Graphics', 'icons8-import-100.png');
            app.Label = uilabel(app.CalibrationToolsUIFigure);
            app.Label.Text = '';
            app.UITable = uitable(app.CalibrationToolsUIFigure);
            app.UITable.ColumnName = {'Dose'; 'Red'; 'Green'; 'Blue'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.DegreeSpinnerLabel = uilabel(app.CalibrationToolsUIFigure);
            app.DegreeSpinnerLabel.Text = 'Degree';
            app.DegreeSpinner = uispinner(app.CalibrationToolsUIFigure);
            app.DegreeSpinner.Value = 3;
            app.FitButton = uibutton(app.CalibrationToolsUIFigure, 'push');
            app.FitButton.ButtonPushedFcn = createCallbackFcn(app, @FitButtonPushed, true);
            app.FitButton.Text = 'Fit';
            app.ChannelDropDownLabel = uilabel(app.CalibrationToolsUIFigure);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.CalibrationToolsUIFigure);
            app.ChannelDropDown.Items = {'Red', 'Green'};
            app.ChannelDropDown.Value = 'Red';
            app.CalibrationFilmTestPanel = uipanel(app.CalibrationToolsUIFigure);
            app.CalibrationFilmTestPanel.Title = 'Calibration Film Test';
            app.LoadFilmButton = uibutton(app.CalibrationFilmTestPanel, 'push');
            app.LoadFilmButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFilmButtonPushed, true);
            app.LoadFilmButton.Text = 'Load Film';
            app.Convert2DoseButton = uibutton(app.CalibrationFilmTestPanel, 'push');
            app.Convert2DoseButton.ButtonPushedFcn = createCallbackFcn(app, @Convert2DoseButtonPushed, true);
            app.Convert2DoseButton.Text = 'Convert2Dose';
            app.MeasureButton = uibutton(app.CalibrationFilmTestPanel, 'push');
            app.MeasureButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureButtonPushed, true);
            app.MeasureButton.Text = 'Measure';
            app.FilterEditField = uieditfield(app.CalibrationFilmTestPanel, 'numeric');
            app.FilterEditField.Value = 3000;
            app.FilterButton = uibutton(app.CalibrationFilmTestPanel, 'push');
            app.FilterButton.ButtonPushedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.FilterButton.Text = 'Filter';
            app.Label_2 = uilabel(app.CalibrationToolsUIFigure);
            app.Label_2.Text = '';
            app.CalibType2Button = uibutton(app.CalibrationToolsUIFigure, 'push');
            app.CalibType2Button.ButtonPushedFcn = createCallbackFcn(app, @CalibType2ButtonPushed, true);
            app.CalibType2Button.Text = 'Calib(Type2)';
            app.CalibType2Button.Icon = fullfile(pathToMLAPP, 'resources', 'Graphics', 'icons8-import-100.png');
            app.ModeDoseLabel = uilabel(app.CalibrationToolsUIFigure);
            app.ModeDoseLabel.Text = 'Mode Dose:';
            app.ModeDoseEditField = uieditfield(app.CalibrationToolsUIFigure, 'numeric');
            app.MeanDoseLabel = uilabel(app.CalibrationToolsUIFigure);
            app.MeanDoseLabel.Text = ' Mean Dose:';
            app.MeanDoseEditField = uieditfield(app.CalibrationToolsUIFigure, 'numeric');
            app.CalibLabel_2 = uilabel(app.CalibrationToolsUIFigure);
            app.CalibLabel_2.Text = 'Calib:';
            app.FilmLabel_2 = uilabel(app.CalibrationToolsUIFigure);
            app.FilmLabel_2.Text = 'Film:';
            app.SaveCalibrationButton = uibutton(app.CalibrationToolsUIFigure, 'push');
            app.SaveCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCalibrationButtonPushed, true);
            app.SaveCalibrationButton.Text = 'Save Calibration';
            app.Panel = uipanel(app.CalibrationToolsUIFigure);
            app.UncorrectedTable = uitable(app.Panel);
            app.UncorrectedTable.ColumnName = '';
            app.UncorrectedTable.RowName = {};
            app.R2Label = uilabel(app.Panel);
            app.R2Label.Interpreter = 'tex';
            app.R2Label.Text = 'R^2:';
            app.R2EditField_2 = uieditfield(app.Panel, 'numeric');
            app.UncorrectedLabel = uilabel(app.Panel);
            app.UncorrectedLabel.Text = 'Uncorrected';
            app.CorrectedLabel = uilabel(app.Panel);
            app.CorrectedLabel.Text = 'Corrected';
            app.CorrectedTable = uitable(app.Panel);
            app.CorrectedTable.ColumnName = '';
            app.CorrectedTable.RowName = {};
            app.R2Label_2 = uilabel(app.Panel);
            app.R2Label_2.Interpreter = 'tex';
            app.R2Label_2.Text = 'R^2: ';
            app.R2EditField = uieditfield(app.Panel, 'numeric');
            % Show the figure after all components are created
            app.CalibrationToolsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CalibrationTools(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CalibrationToolsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CalibrationToolsUIFigure)
        end
    end
end