classdef FWHM_JawSize < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FWHMJawSizeCalculatorUIFigure  matlab.ui.Figure
        YmmEditField                   matlab.ui.control.NumericEditField
        YmmEditFieldLabel              matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        ThresholdEditField             matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel        matlab.ui.control.Label
        MethodDropDown                 matlab.ui.control.DropDown
        MethodDropDownLabel            matlab.ui.control.Label
        CalculateButton                matlab.ui.control.Button
        XmmEditField                   matlab.ui.control.NumericEditField
        XmmEditFieldLabel              matlab.ui.control.Label
        JawSizePanel                   matlab.ui.container.Panel
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        MainApp;
        combinedX; combinedY;
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, ComX, ComY)
            app.MainApp = mainapp;           
            app.combinedX = ComX;
            app.combinedY = ComY;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
                [distanceX, Final_X,  leftX, rightX]  = fn_FWHMCalc(app.combinedX, app.MethodDropDown.Value, app.ThresholdEditField.Value);
                app.XmmEditField.Value = distanceX;
                ProfileX = app.combinedX;
                plot(app.UIAxes, ProfileX(:, 1), ProfileX(:, 2), 'b-', 'LineWidth', 2); % Plot the original profile
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, Final_X(:,1), Final_X(:,2), 'r-', 'MarkerSize', 4); % Plot X_values
                plot(app.UIAxes, ProfileX(leftX(2), 1), ProfileX(leftX(2), 2), 'bo', 'MarkerSize', 6, 'MarkerFaceColor','g'); % Plot left
                plot(app.UIAxes, ProfileX(rightX(2) + size(ProfileX, 1) / 2, 1), ProfileX(rightX(2) + size(ProfileX, 1) / 2, 2), 'bo', 'MarkerSize', 6, 'MarkerFaceColor','g'); % Plot right
                hold(app.UIAxes, 'off');
                

                [distance2, Final_Y,  leftY, rightY]  = fn_FWHMCalc(app.combinedY,  app.MethodDropDown.Value, app.ThresholdEditField.Value);
                app.YmmEditField.Value = distance2;
                ProfileY = app.combinedY;
                plot(app.UIAxes_2, ProfileY(:, 1), ProfileY(:, 2), 'b-', 'LineWidth', 2); % Plot the original profile
                hold(app.UIAxes_2, 'on');
                plot(app.UIAxes_2, Final_Y(:,1), Final_Y(:,2), 'r-', 'MarkerSize', 4); % Plot X_values
                plot(app.UIAxes_2, ProfileY(leftY(2), 1), ProfileY(leftY(2), 2), 'bo', 'MarkerSize', 6, 'MarkerFaceColor','g'); % Plot left
                plot(app.UIAxes_2, ProfileY(rightY(2) + size(ProfileY, 1) / 2, 1), ProfileY(rightY(2) + size(ProfileY, 1) / 2, 2), 'bo', 'MarkerSize', 6,  'MarkerFaceColor','g'); % Plot right
                hold(app.UIAxes_2, 'off');

        end

        % Close request function: FWHMJawSizeCalculatorUIFigure
        function FWHMJawSizeCalculatorUIFigureCloseRequest(app, event)
             app.MainApp.JawSize.Enable = "on";
             delete(app)        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.FWHMJawSizeCalculatorUIFigure = uifigure('Visible', 'off');
            app.FWHMJawSizeCalculatorUIFigure.Name = 'FWHM JawSize Calculator';
            app.FWHMJawSizeCalculatorUIFigure.Icon = fullfile(pathToMLAPP, 'dlgapps_resources', 'icons8-jaw-66.png');
            app.FWHMJawSizeCalculatorUIFigure.CloseRequestFcn = createCallbackFcn(app, @FWHMJawSizeCalculatorUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.FWHMJawSizeCalculatorUIFigure);
            title(app.UIAxes, 'X Profile')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.FWHMJawSizeCalculatorUIFigure);
            title(app.UIAxes_2, 'Y Profile')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.JawSizePanel = uipanel(app.FWHMJawSizeCalculatorUIFigure);
            app.JawSizePanel.Title = 'Jaw Size';
            app.XmmEditFieldLabel = uilabel(app.FWHMJawSizeCalculatorUIFigure);
            app.XmmEditFieldLabel.Text = 'X (mm):';
            app.XmmEditField = uieditfield(app.FWHMJawSizeCalculatorUIFigure, 'numeric');
            app.XmmEditField.ValueDisplayFormat = '%.3f';
            app.CalculateButton = uibutton(app.FWHMJawSizeCalculatorUIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Panel = uipanel(app.FWHMJawSizeCalculatorUIFigure);
            app.MethodDropDownLabel = uilabel(app.Panel);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.Panel);
            app.MethodDropDown.Items = {'Maximum', 'Mean', 'Median', ''};
            app.MethodDropDown.Value = 'Mean';
            app.ThresholdEditFieldLabel = uilabel(app.Panel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.Panel, 'numeric');
            app.ThresholdEditField.Limits = [0 1];
            app.ThresholdEditField.Value = 0.2;
            app.YmmEditFieldLabel = uilabel(app.FWHMJawSizeCalculatorUIFigure);
            app.YmmEditFieldLabel.Text = 'Y (mm):';
            app.YmmEditField = uieditfield(app.FWHMJawSizeCalculatorUIFigure, 'numeric');
            app.YmmEditField.ValueDisplayFormat = '%.3f';
            % Show the figure after all components are created
            app.FWHMJawSizeCalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FWHM_JawSize(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FWHMJawSizeCalculatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FWHMJawSizeCalculatorUIFigure)
        end
    end
end