classdef Synchronicity < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SynchronicityUIFigure         matlab.ui.Figure
        ImportFilmButton              matlab.ui.control.Button
        EditField                     matlab.ui.control.EditField
        ResultsPanel                  matlab.ui.container.Panel
        MaxErrorEditField_2           matlab.ui.control.NumericEditField
        MaxErrorEditField_2Label      matlab.ui.control.Label
        MaxErrorEditField             matlab.ui.control.NumericEditField
        MaxErrorEditFieldLabel        matlab.ui.control.Label
        UITable_2                     matlab.ui.control.Table
        UITable                       matlab.ui.control.Table
        ToolsPanel                    matlab.ui.container.Panel
        TransposeButton               matlab.ui.control.Button
        ProcessButton                 matlab.ui.control.Button
        CheeseDiameterEditField       matlab.ui.control.NumericEditField
        CheeseDiameterEditFieldLabel  matlab.ui.control.Label
        AngleBaselneEditField         matlab.ui.control.NumericEditField
        AngleBaselneEditFieldLabel    matlab.ui.control.Label
        CutoffEditField               matlab.ui.control.NumericEditField
        CutoffEditFieldLabel          matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        DPI;
        film;
        film_all;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportFilmButton
        function ImportFilmButtonPushed(app, event)
           
            [file, path ] = uigetfile({'*.tif'; '*.tiff'; '*.TIFF'; '*.TIF'}, 'Please select the film file' );
            film_name = (fullfile(path, file));

            app.EditField.Value = film_name;
            film_raw = imread(film_name);  
            app.film =double(rgb2gray(film_raw(:,:,1:3)));
            app.film_all = mat2gray(app.film);
            info = imfinfo(film_name);
            app.DPI = 25.4/info.XResolution;
            DPI_alt = 25.4 /300;

            if (app.DPI - DPI_alt) ~= 0
                msgbox('Img info DPI not matching with calulated DPI. Values have been overriddern!');
                app.DPI = DPI_alt;
            end

            imshow(app.film_all, 'Parent',app.UIAxes);

        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            img = app.film;
            cutoff = app.CutoffEditField.Value;
            angle_baseline = app.AngleBaselneEditField.Value;

            cheese_diameter = 300;
            pixel_size = 25.4 / app.DPI;
            
            % if size(img, 1) < size(img, 2)
            %     img = img';
            % end
                
            img = img ./ max(img(:));  % Normalizing
            img = imadjust(img, [min(img(:)) 1.0]); %Contrast
            img = imadjust(img, [0.1 1.0]); %second contrast
           % img = img(cutoff+1:end-cutoff,cutoff+1:end-cutoff); %Cropping the image to the given threshold
            
            imshow(img, 'Parent', app.UIAxes);
            
            [peaks, locs] = findpeaks(imhist(img, 100));
            [max1, loc1] = max(peaks);
            [~, loc2] = max(peaks(peaks < max1));
            binary_threshold = (locs(loc1) + locs(loc2)) / 2 / 100;
            
            b = img < binary_threshold;
            b = imfill(b, 'holes');
            b = bwareaopen(b, 10000);
            stats = regionprops(b, 'Centroid', 'Area');
            pts = sortrows(reshape([stats.Centroid], [2 15])', 1);
            pts = sortrows(pts,1);% to sort the points (by Chenyang 04/28/2023)
            pts = [sortrows(pts(1:5, :), 2); sortrows(pts(6:10, :), 2); sortrows(pts(11:15, :), 2)];
            d = pixel_size * pdist2(pts, pts);
            
            horizontal_distance = [d(1, 2) d(2, 3) d(3, 4) d(4, 5); d(6, 7) d(7, 8) d(8, 9) d(9, 10); d(11, 12) d(12, 13) d(13, 14) d(14, 15)];
            degrees = 360 * horizontal_distance / (pi * cheese_diameter);
            app.UITable.Data = horizontal_distance;

            vertical_distance = [d(1, 6) d(2, 7) d(3, 8) d(4, 9) d(5, 10); d(6, 11) d(7, 12) d(8, 13) d(9, 14) d(10, 15)];
            app.UITable_2.Data = vertical_distance;
           
            disp('Angle formed by two radii to horizontally adjacent squares [degree]'); 
            disp(degrees);
            max_error_2 = max(abs(degrees(:) - angle_baseline));
            app.MaxErrorEditField.Value = max_error_2;

            if max_error_2 > 0.5
                disp(abs(degrees - angle_baseline) <= 0.5);
                fprintf('   %s Max error: %.4f\n', char(10006), max_error_2);
            else
                fprintf('   %s Max error: %.4f\n', char(10004), max_error_2);
            end
            disp('Vertical distance between adjacent squares [mm]');
            disp(vertical_distance);
            max_error_3 = max(abs(vertical_distance(:) - 42));

            app.MaxErrorEditField_2.Value = max_error_3;
            if max_error_3 > 0.5
                disp(abs(vertical_distance - 42) <= 0.5);
                fprintf('   %s Max error: %.4f\n', char(10006), max_error_3);
            else
                fprintf('   %s Max error: %.4f\n', char(10004), max_error_3);
            end
            

            imshow(img', [], 'Parent', app.UIAxes);
            daspect(app.UIAxes, [1 1 1]);
            hold(app.UIAxes, 'on');
            plot( app.UIAxes, ...
                pts(1:2, 2), pts(1:2, 1), ...
                pts(2:3, 2), pts(2:3, 1), ...
                pts(3:4, 2), pts(3:4, 1), ...
                pts(4:5, 2), pts(4:5, 1), ...
                pts(6:7, 2), pts(6:7, 1), ...
                pts(7:8, 2), pts(7:8, 1), ...
                pts(8:9, 2), pts(8:9, 1), ...
                pts(9:10, 2), pts(9:10, 1), ...
                pts(11:12, 2), pts(11:12, 1), ...
                pts(12:13, 2), pts(12:13, 1), ...
                pts(13:14, 2), pts(13:14, 1), ...
                pts(14:15, 2), pts(14:15, 1), ...
                pts([1 6], 2), pts([1 6], 1), ...
                pts([2 7], 2), pts([2 7], 1), ...
                pts([3 8], 2), pts([3 8], 1), ...
                pts([4 9], 2), pts([4 9], 1), ...
                pts([5 10], 2), pts([5 10], 1), ...
                pts([6 11], 2), pts([6 11], 1), ...
                pts([7 12], 2), pts([7 12], 1), ...
                pts([8 13], 2), pts([8 13], 1), ...
                pts([9 14], 2), pts([9 14], 1), ...
                pts([10 15], 2), pts([10 15], 1), 'color', 'red');
            hold(app.UIAxes, 'off');
        end

        % Button pushed function: TransposeButton
        function TransposeButtonPushed(app, event)
            app.film = imrotate(app.film, 90);
            app.film_all  = mat2gray(app.film);
            imshow(app.film_all, "Parent", app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SynchronicityUIFigure = uifigure('Visible', 'off');
            app.SynchronicityUIFigure.Name = 'Synchronicity';
            app.UIAxes = uiaxes(app.SynchronicityUIFigure);
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTickLabel = '';
            app.ToolsPanel = uipanel(app.SynchronicityUIFigure);
            app.ToolsPanel.Title = 'Tools';
            app.CutoffEditFieldLabel = uilabel(app.ToolsPanel);
            app.CutoffEditFieldLabel.Text = 'Cutoff';
            app.CutoffEditField = uieditfield(app.ToolsPanel, 'numeric');
            app.CutoffEditField.Value = 300;
            app.AngleBaselneEditFieldLabel = uilabel(app.ToolsPanel);
            app.AngleBaselneEditFieldLabel.Text = 'Angle Baselne';
            app.AngleBaselneEditField = uieditfield(app.ToolsPanel, 'numeric');
            app.AngleBaselneEditField.Value = 14.4;
            app.CheeseDiameterEditFieldLabel = uilabel(app.ToolsPanel);
            app.CheeseDiameterEditFieldLabel.Text = 'Cheese Diameter';
            app.CheeseDiameterEditField = uieditfield(app.ToolsPanel, 'numeric');
            app.CheeseDiameterEditField.Value = 300;
            app.ProcessButton = uibutton(app.ToolsPanel, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.TransposeButton = uibutton(app.ToolsPanel, 'push');
            app.TransposeButton.ButtonPushedFcn = createCallbackFcn(app, @TransposeButtonPushed, true);
            app.TransposeButton.Text = 'Transpose';
            app.ResultsPanel = uipanel(app.SynchronicityUIFigure);
            app.ResultsPanel.Title = 'Results';
            app.UITable = uitable(app.ResultsPanel);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable_2 = uitable(app.ResultsPanel);
            app.UITable_2.ColumnName = '';
            app.UITable_2.RowName = {};
            app.MaxErrorEditFieldLabel = uilabel(app.ResultsPanel);
            app.MaxErrorEditFieldLabel.Text = 'Max Error:';
            app.MaxErrorEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.MaxErrorEditField_2Label = uilabel(app.ResultsPanel);
            app.MaxErrorEditField_2Label.Text = 'Max Error:';
            app.MaxErrorEditField_2 = uieditfield(app.ResultsPanel, 'numeric');
            app.EditField = uieditfield(app.SynchronicityUIFigure, 'text');
            app.ImportFilmButton = uibutton(app.SynchronicityUIFigure, 'push');
            app.ImportFilmButton.ButtonPushedFcn = createCallbackFcn(app, @ImportFilmButtonPushed, true);
            app.ImportFilmButton.Text = 'Import Film';
            % Show the figure after all components are created
            app.SynchronicityUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Synchronicity

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SynchronicityUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SynchronicityUIFigure)
        end
    end
end