classdef FilmDosimetry < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FilmDosimetryUIFigure      matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        CalibrationTab             matlab.ui.container.Tab
        Panel_36                   matlab.ui.container.Panel
        Button_22                  matlab.ui.control.Button
        FilmToolsLabel             matlab.ui.control.Label
        JawSize                    matlab.ui.control.Button
        Button_15                  matlab.ui.control.Button
        Panel_35                   matlab.ui.container.Panel
        CalibrationToolsLabel      matlab.ui.control.Label
        Button_16                  matlab.ui.control.Button
        Button_17                  matlab.ui.control.Button
        Button_18                  matlab.ui.control.Button
        Panel_32                   matlab.ui.container.Panel
        NotReadyLabel              matlab.ui.control.Label
        CalibrationCurveLabel      matlab.ui.control.Label
        ColorMapDropDown_3         matlab.ui.control.DropDown
        ColorMapDropDown_3Label    matlab.ui.control.Label
        FilmButton                 matlab.ui.control.Button
        DoseButton                 matlab.ui.control.Button
        Panel_20                   matlab.ui.container.Panel
        IMContrastButton           matlab.ui.control.Button
        UIAxes4                    matlab.ui.control.UIAxes
        Panel_14                   matlab.ui.container.Panel
        UITable                    matlab.ui.control.Table
        UIAxes9                    matlab.ui.control.UIAxes
        UIAxes9_2                  matlab.ui.control.UIAxes
        Panel_28                   matlab.ui.container.Panel
        ContrastSlider             matlab.ui.control.Slider
        Panel_31                   matlab.ui.container.Panel
        FiltersLabel               matlab.ui.control.Label
        FilterNoiseEditField       matlab.ui.control.NumericEditField
        FilterNoiseButton          matlab.ui.control.Button
        SizeEditField              matlab.ui.control.NumericEditField
        MedianFilterButton         matlab.ui.control.Button
        Panel_22                   matlab.ui.container.Panel
        SmoothDropDown_2           matlab.ui.control.DropDown
        SmoothWIn_2                matlab.ui.control.NumericEditField
        SmoothButton               matlab.ui.control.Button
        Panel_2                    matlab.ui.container.Panel
        EditField_5                matlab.ui.control.NumericEditField
        DropDown_7                 matlab.ui.control.DropDown
        InterpolationButton        matlab.ui.control.Button
        TransformationLabel        matlab.ui.control.Label
        Panel_30                   matlab.ui.container.Panel
        Panel_26                   matlab.ui.container.Panel
        ManualCropButton           matlab.ui.control.Button
        CenterCropButton           matlab.ui.control.Button
        WidthLabel                 matlab.ui.control.Label
        HeightLabel                matlab.ui.control.Label
        WidthCC                    matlab.ui.control.NumericEditField
        HeightCC                   matlab.ui.control.NumericEditField
        FlipVButton                matlab.ui.control.Button
        FilpHButton                matlab.ui.control.Button
        CCWButton                  matlab.ui.control.Button
        CWButton                   matlab.ui.control.Button
        Panel_29                   matlab.ui.container.Panel
        Film2DoseLabel             matlab.ui.control.Label
        AutoCenterButton           matlab.ui.control.Button
        PickFilmCenterButton_2     matlab.ui.control.Button
        UpdateFilmDoseButton       matlab.ui.control.Button
        Button_20                  matlab.ui.control.Button
        ConverttodoseButton        matlab.ui.control.Button
        ManuallyAlignButton        matlab.ui.control.Button
        AutoAlignImageButton       matlab.ui.control.Button
        ProfileToolsLabel          matlab.ui.control.Label
        Panel_27                   matlab.ui.container.Panel
        cGyEditField               matlab.ui.control.NumericEditField
        cGyEditFieldLabel          matlab.ui.control.Label
        MeasurementLabel           matlab.ui.control.Label
        AreaButton                 matlab.ui.control.Button
        DistanceButton             matlab.ui.control.Button
        ROIDoseButton              matlab.ui.control.Button
        ButtonGroup_2              matlab.ui.container.ButtonGroup
        NormalizeCheckBox          matlab.ui.control.CheckBox
        FilmProfileButton          matlab.ui.control.Button
        Button_19                  matlab.ui.control.Button
        ManualButton               matlab.ui.control.RadioButton
        CenterButton               matlab.ui.control.RadioButton
        DicomDoseViewerTab         matlab.ui.container.Tab
        Panel_25                   matlab.ui.container.Panel
        UpdateButton_2             matlab.ui.control.Button
        PlaneLabel                 matlab.ui.control.Label
        ThresholdEditField         matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel    matlab.ui.control.Label
        UIAxes10                   matlab.ui.control.UIAxes
        Panel_13                   matlab.ui.container.Panel
        GyEditField                matlab.ui.control.NumericEditField
        GyEditFieldLabel           matlab.ui.control.Label
        XYSlider                   matlab.ui.control.Slider
        Button_4                   matlab.ui.control.Button
        Button_7                   matlab.ui.control.Button
        XYFigure                   matlab.ui.control.UIAxes
        Panel_12                   matlab.ui.container.Panel
        GyEditField_2              matlab.ui.control.NumericEditField
        GyEditField_2Label         matlab.ui.control.Label
        XZSlider                   matlab.ui.control.Slider
        Button_3                   matlab.ui.control.Button
        Button_6                   matlab.ui.control.Button
        XZFigure                   matlab.ui.control.UIAxes
        Panel_11                   matlab.ui.container.Panel
        GyEditField_3              matlab.ui.control.NumericEditField
        GyEditField_3Label         matlab.ui.control.Label
        YZSlider                   matlab.ui.control.Slider
        Button_2                   matlab.ui.control.Button
        Button_5                   matlab.ui.control.Button
        YZFigure                   matlab.ui.control.UIAxes
        RotatedDoseAlignmentPanel  matlab.ui.container.Panel
        OGPlane                    matlab.ui.control.DropDown
        RotatedPlane               matlab.ui.control.DropDown
        Label_12                   matlab.ui.control.Label
        Label_11                   matlab.ui.control.Label
        Label_10                   matlab.ui.control.Label
        Label_9                    matlab.ui.control.Label
        RotatedFigure              matlab.ui.control.UIAxes
        OriginalFigure             matlab.ui.control.UIAxes
        DosePlaneCalculatorPanel   matlab.ui.container.Panel
        Button_11                  matlab.ui.control.Button
        StepsizepixelEditField     matlab.ui.control.NumericEditField
        vButton                    matlab.ui.control.Button
        Button_10                  matlab.ui.control.Button
        Button_12                  matlab.ui.control.Button
        RotatedDoseButton          matlab.ui.control.Button
        Panel_8                    matlab.ui.container.Panel
        UpdateDicomDoseButton      matlab.ui.control.Button
        DropDown_3                 matlab.ui.control.DropDown
        SelecttheplaneLabel        matlab.ui.control.Label
        Panel_7                    matlab.ui.container.Panel
        YawEditField               matlab.ui.control.NumericEditField
        YawEditFieldLabel          matlab.ui.control.Label
        RollEditField              matlab.ui.control.NumericEditField
        RollEditFieldLabel         matlab.ui.control.Label
        PitchEditField             matlab.ui.control.NumericEditField
        PitchEditFieldLabel        matlab.ui.control.Label
        RotateButton               matlab.ui.control.Button
        Panel_4                    matlab.ui.container.Panel
        Panel_5                    matlab.ui.container.Panel
        FrameEditField             matlab.ui.control.NumericEditField
        FrameEditFieldLabel        matlab.ui.control.Label
        RowEditField               matlab.ui.control.NumericEditField
        RowEditFieldLabel          matlab.ui.control.Label
        ColumnEditField            matlab.ui.control.NumericEditField
        ColumnEditFieldLabel       matlab.ui.control.Label
        CalculateButton            matlab.ui.control.Button
        Label_22                   matlab.ui.control.Label
        Label_21                   matlab.ui.control.Label
        Label_20                   matlab.ui.control.Label
        Label_19                   matlab.ui.control.Label
        Label_18                   matlab.ui.control.Label
        Label_17                   matlab.ui.control.Label
        EditField_11               matlab.ui.control.NumericEditField
        EditField_10               matlab.ui.control.NumericEditField
        EditField_9                matlab.ui.control.NumericEditField
        EditField_8                matlab.ui.control.NumericEditField
        EditField_7                matlab.ui.control.NumericEditField
        EditField_6                matlab.ui.control.NumericEditField
        ZmmEditField               matlab.ui.control.NumericEditField
        ZmmEditFieldLabel          matlab.ui.control.Label
        YmmEditField               matlab.ui.control.NumericEditField
        YmmEditFieldLabel          matlab.ui.control.Label
        XmmEditField               matlab.ui.control.NumericEditField
        XmmEditFieldLabel          matlab.ui.control.Label
        SystemLabel                matlab.ui.control.Label
        Panel_33                   matlab.ui.container.Panel
        TPSButton                  matlab.ui.control.Button
        ColorMapDropDown_2         matlab.ui.control.DropDown
        ColorMapDropDown_2Label    matlab.ui.control.Label
        MRNEditField_2             matlab.ui.control.EditField
        MRNLabel_2                 matlab.ui.control.Label
        MaxPlaneDoseCheckBox       matlab.ui.control.CheckBox
        Button_9                   matlab.ui.control.Button
        RDDoseButton               matlab.ui.control.Button
        AnalysisTab                matlab.ui.container.Tab
        Panel_34                   matlab.ui.container.Panel
        ColorMapDropDown           matlab.ui.control.DropDown
        ColorMapDropDownLabel      matlab.ui.control.Label
        AllFileName                matlab.ui.control.EditField
        PlanLabel                  matlab.ui.control.Label
        MRNEditField               matlab.ui.control.EditField
        MRNLabel                   matlab.ui.control.Label
        FilmButton_2               matlab.ui.control.Button
        Button_21                  matlab.ui.control.Button
        Panel_19                   matlab.ui.container.Panel
        GammaToolsPanel            matlab.ui.container.Panel
        SmoothWIn                  matlab.ui.control.NumericEditField
        SmoothDropDown             matlab.ui.control.DropDown
        SmoothLabel                matlab.ui.control.Label
        GridDropDown               matlab.ui.control.DropDown
        GridLabel                  matlab.ui.control.Label
        SingalEditField            matlab.ui.control.NumericEditField
        SingalLabel                matlab.ui.control.Label
        ButtonGroup                matlab.ui.container.ButtonGroup
        DropDown_8                 matlab.ui.control.DropDown
        LocalButton                matlab.ui.control.RadioButton
        GlobalButton               matlab.ui.control.RadioButton
        ResEditField               matlab.ui.control.NumericEditField
        ResLabel                   matlab.ui.control.Label
        PlanFilmButton             matlab.ui.control.Button
        GammaEvalButton            matlab.ui.control.Button
        DTAmmEditField             matlab.ui.control.NumericEditField
        DTAmmLabel                 matlab.ui.control.Label
        DDEditField                matlab.ui.control.NumericEditField
        DDLabel                    matlab.ui.control.Label
        ResultPanel                matlab.ui.container.Panel
        DoseScaledEditField        matlab.ui.control.NumericEditField
        DoseScaledLabel            matlab.ui.control.Label
        EditField_4                matlab.ui.control.NumericEditField
        Label_13                   matlab.ui.control.Label
        DDDTALabel                 matlab.ui.control.Label
        EditField2                 matlab.ui.control.NumericEditField
        EditField_3                matlab.ui.control.NumericEditField
        YShiftsmmEditField         matlab.ui.control.NumericEditField
        YShiftsmmEditFieldLabel    matlab.ui.control.Label
        XShiftsmmEditField         matlab.ui.control.NumericEditField
        XShiftsmmEditFieldLabel    matlab.ui.control.Label
        UIAxes11                   matlab.ui.control.UIAxes
        UIAxes6                    matlab.ui.control.UIAxes
        Panel_18                   matlab.ui.container.Panel
        Panel_21                   matlab.ui.container.Panel
        YDDEditField               matlab.ui.control.NumericEditField
        DDLabel_2                  matlab.ui.control.Label
        XDDEditField               matlab.ui.control.NumericEditField
        XDDEditFieldLabel          matlab.ui.control.Label
        DropDown_9                 matlab.ui.control.DropDown
        UIAxes3                    matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
        Panel_17                   matlab.ui.container.Panel
        FilmSlider                 matlab.ui.control.Slider
        FilmDoseButton             matlab.ui.control.Button
        FigFilmDose                matlab.ui.control.UIAxes
        Panel_16                   matlab.ui.container.Panel
        PlanDoseButton             matlab.ui.control.Button
        TPSSlider                  matlab.ui.control.Slider
        FigDicomDose               matlab.ui.control.UIAxes
        AlignmentPanel             matlab.ui.container.Panel
        PerformGammaCheckBox       matlab.ui.control.CheckBox
        Panel_10                   matlab.ui.container.Panel
        StepsizepixelEditField_2   matlab.ui.control.NumericEditField
        DownButton_2               matlab.ui.control.Button
        UpButton_2                 matlab.ui.control.Button
        RightButton_2              matlab.ui.control.Button
        LeftButton_2               matlab.ui.control.Button
        LocateCenterButton         matlab.ui.control.Button
        DropDown_2                 matlab.ui.control.DropDown
        DoseandProfileToolsPanel   matlab.ui.container.Panel
        CompareProfileButton       matlab.ui.control.Button
        ScaleVal                   matlab.ui.control.NumericEditField
        ProfileButton              matlab.ui.control.Button
        FxEditField                matlab.ui.control.NumericEditField
        FxEditFieldLabel           matlab.ui.control.Label
        FixFilmResButton           matlab.ui.control.Button
        DPIEditField               matlab.ui.control.NumericEditField
        DPIEditFieldLabel          matlab.ui.control.Label
        CropButton                 matlab.ui.control.Button
        TPSCheckBox                matlab.ui.control.CheckBox
        FilmCheckBox               matlab.ui.control.CheckBox
        ScaleTPSButton             matlab.ui.control.Button
        Label_16                   matlab.ui.control.Label
        Label_15                   matlab.ui.control.Label
    end


    properties (Access = private)
        calib_film;
        TiffDPI;
        Initial_Fit; Final_Fit; Fit_type;
        Film_dose; TPS_dose;Film_dose_gamma;
        Generate_button = 0;
        Filmhasbeenpushed = 'false';
        X_mm = 0.5; Y_mm = 0.5; Z_mm = 0.5;
        ImagePP = [];
        window_size = 1;
        Grid_scaling = 1;
        SliceThickness = 1;PixelSpacing_Y = 1; PixelSpacing_X = 1;
        ImageOrent ;
        NumRows = 512; NumColumns = 512;NumFrames = 250;
        DicomInfo; DicomVolume ; RotateDicomVolume = [];Manufc; saveVolume; PlaneInfo; PlanPushed;
        slider_current1 = 1;slider_current2 = 1;slider_current3 = 1;
        yzPlane;xyPlane;xzPlane;
        xProfileTPS;yProfileTPS; xProfileFilm; yProfileFilm;
        Film_Center; TPS_Center;
        fx = 1;DoseScaling = 1;
        FilmRows; FilmCols;
        X_dicom_cm; Y_dicom_cm; Z_dicom_cm;x_pro, y_pro;
        PlaneCenter; currentCenter; new_center;
        xTPSPoint , yTPSPoint;
        Xshift_counter = 0; Yshift_counter = 0;
        Path;
        VolRotStatus = 'false';
        x_dis_TPS, y_dis_TPS;
        x_dis_Film, y_dis_Film;
        gammamap;
        combinedX ;
        combinedY ;
        DialogApp;
        Post_pitch = 0;Post_yaw = 0; Post_roll = 0;
        ColMap = 'jet';

        Interpolation; Field_X;Field_Y;
        TPSInterpolation;

        
    end

    properties (Access = public)
        CurrentPointGraphic; % Description
    end

    methods (Access = private)

        function [ X_pos, Y_pos] = ProfilePoint(~, UIAxes)
                % Create the crosshair on the UIAxes4
                h = drawcrosshair(UIAxes, 'LineWidth', 0.5, 'Color', 'Red');
                
                % Add listener to update position when the crosshair is moved
                addlistener(h, 'MovingROI', @(src, evt) updateCrosshairPosition(app, src));
                
                % Get the initial position of the crosshair intersection
                Position = h.Position;
                X_pos = Position(1);
                Y_pos = Position(2);
            
                % Nested function to update the crosshair position
                function updateCrosshairPosition(~, crosshair)
                    Position = crosshair.Position;
                end
        end


        function TPSPlot(~, UIAxes, xy_dis, xyProfile)
            plot(UIAxes,xy_dis,  xyProfile, 'r-');

        end

        function FilmPlot(~, UIAxes1, xy_dis, xyProfile1)
            plot(UIAxes1,xy_dis, xyProfile1, 'b-');

        end

        function GraphTickMode(~, UIAx, Title)
            UIAx.Title.String = Title;
            UIAx.XTickMode = 'auto';
            UIAx.YTickMode = 'auto';
            UIAx.XTickLabelMode = 'auto';
            UIAx.YTickLabelMode = 'auto';
            axis(UIAx, 'on');
            UIAx.Box = 'on';
            UIAx.XLimitMethod= 'tight';
            UIAx.YLimitMethod= 'tight';
        end

        function [Initial_coefficients, Final_Coefficeints, uncorr_R2, corr_R2] = DoseFitting(~, Dose_ch, channel_correct, channel_lg)
            Initial_coefficients  = polyfit(channel_correct, channel_lg,3);
            After_corr = polyval(Initial_coefficients, channel_correct);
            Final_Coefficeints = polyfit(After_corr, Dose_ch, 3);

            y_pred1 = polyval(Initial_coefficients, channel_correct);
            uncorr_R2 = 1 - sum((channel_lg - y_pred1).^2) / sum((channel_lg - mean(channel_lg)).^2);

            y_pred = polyval(Final_Coefficeints, After_corr);
            corr_R2 = 1 - sum((Dose_ch - y_pred).^2) / sum((Dose_ch - mean(Dose_ch)).^2);
        end


        % Function to delete text label
        function deleteText(~, src)
            delete(src); % Delete the text label
        end

        
    end

    methods (Access = public)
        
        function updateFits(app, fit_initial, fit_final, fittype)
            app.Initial_Fit = fit_initial;
            app.Final_Fit = fit_final;
            app.Fit_type = fittype;  
            app.NotReadyLabel.Text = "Ready!";
            app.NotReadyLabel.BackgroundColor = "Green";
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc; clearvars; close all;     
            folderPath = fullfile(fileparts(mfilename('fullpath')));
            addpath(folderPath);
            addpath(folderPath, "DialogApps\");
            addpath(folderPath, "functions\");
            addpath(folderPath, "ml_resources\");


        end

        % Button pushed function: Button_18
        function Button_18Pushed(app, event)

            [filename, filePaths] = uigetfile({'*/*.tif'}, 'Select the calibration films;', 'MultiSelect', 'on', app.Path);

            if isequal(filename,  0)
                return;
            else
                app.Path = filePaths;
            end


            if iscell(filename)


                % Read the images and store them in a cell array
                images = cell(1, numel(filename));

                for i = 1 :numel(filename)
                    img = (fullfile(filePaths, filename{i}));
                    images{i} = imrotate(imread(img), 270);
                end
                max_height = max(cellfun(@(x) size(x, 1), images)); % Find the maximum height among all images

                % Resize images to have the same height
                resizedImages = cellfun(@(x) imresize(x, [max_height, NaN]), images, 'UniformOutput', false);

                % Concatenate images horizontally
                concatenatedImage = cat(2, resizedImages{:});

                app.calib_film = concatenatedImage;
                imshow(app.calib_film, 'Parent', app.UIAxes4);

            else
                app.calib_film = imread(fullfile(filePaths, filename));
                imshow(app.calib_film, 'Parent', app.UIAxes4);
            end
        end

        % Button pushed function: CWButton
        function CWButtonPushed(app, event)
            app.calib_film = imrotate(app.calib_film, 90);
            imshow(app.calib_film, 'Parent', app.UIAxes4);
            if numel(size(app.calib_film)) >2
            else
                ContrastSliderValueChanged(app);
            end

            Img_size = size(app.calib_film);
            app.HeightCC.Value = Img_size(1);
            app.WidthCC.Value = Img_size(2);
        end

        % Button pushed function: CCWButton
        function CCWButtonPushed(app, event)
            app.calib_film = imrotate(app.calib_film, 270);
            imshow(app.calib_film, 'Parent', app.UIAxes4);
            if numel(size(app.calib_film)) >2
            else
                ContrastSliderValueChanged(app);
            end

            Img_size = size(app.calib_film);
            app.HeightCC.Value = Img_size(1);
            app.WidthCC.Value = Img_size(2);
        end

        % Button pushed function: FilpHButton
        function FilpHButtonPushed(app, event)
            app.calib_film = fliplr(app.calib_film);
            imshow(app.calib_film, 'Parent', app.UIAxes4);
            if numel(size(app.calib_film)) >2
            else
                ContrastSliderValueChanged(app);
            end
        end

        % Button pushed function: FlipVButton
        function FlipVButtonPushed(app, event)
            app.calib_film = flipud(app.calib_film);
            imshow(app.calib_film, 'Parent', app.UIAxes4);
            if numel(size(app.calib_film)) >2
            else
                ContrastSliderValueChanged(app);
            end
        end

        % Button pushed function: CropButton
        function CropButtonPushed(app, event)
            rect = drawrectangle(app.FigFilmDose);
            pos = rect.Position;

            if ~isempty(app.Film_dose)
                app.Film_dose = imcrop(app.Film_dose, pos);
                imshow(mat2gray(app.Film_dose), 'Parent', app.FigFilmDose);
                colormap(app.FigFilmDose, app.ColMap);
                GraphTickMode(app, app.FigFilmDose, 'Film Dose');
                app.FilmSlider.Value = app.TPSSlider.Value * app.DoseScaling;
            else
                return;
            end

            if numel(size(app.calib_film)) >2
            else
                ContrastSliderValueChanged(app);
            end
            CompareProfileButtonPushed(app);

        end

        % Button pushed function: Button_17
        function Button_17Pushed(app, event)
            columnNames = {'Dose', 'Red', 'Green', 'Blue'};
            initialData = zeros(0, numel(columnNames));
            app.UITable.Data = initialData;
            app.UITable.ColumnName = columnNames;
            imshow(app.calib_film, 'Parent', app.UIAxes4);

            CurrentROI = [];
            while true
                if ~isempty(CurrentROI) && ishandle(CurrentROI)
                    delete(CurrentROI);
                end


                imshow(app.calib_film);

                % Draw rectangle on the current subplot
                ROI = drawrectangle;

                CurrentROI = ROI;
                if ~isvalid(ROI.Parent)
                    return;  % Break out of the loop if the user closes the figure
                end


                Dose_MU = str2double(inputdlg('Enter the corresponding Dose/MU'));

                X1 = round(ROI.Position(1));
                Y1 = round(ROI.Position(2));
                X2 = round(X1 + ROI.Position(3) - 1);
                Y2 = round(Y1 + ROI.Position(4) - 1);

                % Extracting color channels from rotated_image, not X
                Red_channel = mean2(double(app.calib_film(Y1:Y2, X1:X2, 1)));
                Green_channel = mean2(double(app.calib_film(Y1:Y2, X1:X2, 2)));
                Blue_channel = mean2(double(app.calib_film(Y1:Y2, X1:X2, 3)));

                % Assigning values to the table
                newRow = [Dose_MU, Red_channel, Green_channel, Blue_channel];

                if ~isempty(Dose_MU)
                    currentData = app.UITable.Data;
                    app.UITable.Data = [currentData; newRow];
                    app.Generate_button = 1;
                end

            end

        end

        % Button pushed function: FilmButton
        function FilmButtonPushed(app, event)

            [data2, path2] = uigetfile({'*.tif'}, 'Please select the image file', app.Path);
            if isequal(data2, 0)
                return;
            else
                app.Path = path2;
            end
            fullFilePath = fullfile(path2, data2);
            app.calib_film = imread(fullFilePath);
            
            film_info = imfinfo(fullFilePath);

            if film_info.SamplesPerPixel > 3
                app.calib_film = app.calib_film(:, : , 1:3);
            end
            
            app.TiffDPI = fn_DPICalculator(fullFilePath);
            imshow(app.calib_film, 'Parent', app.UIAxes4);  
            axis(app.UIAxes4, 'image');

            
            app.UIAxes4.XTickMode = 'auto';
            app.UIAxes4.YTickMode = 'auto';
            

            Img_size = size(app.calib_film);
            app.HeightCC.Value = Img_size(1);
            app.WidthCC.Value = Img_size(2);
            
            CWButtonPushed(app);
            FlipVButtonPushed(app);

        end

        % Button pushed function: ConverttodoseButton
        function ConverttodoseButtonPushed(app, event)

            if strcmp(app.NotReadyLabel.Text, 'Not Ready!')
                msgbox('No Calibration files loaded!');
                return;
            end
            
             Red_channel = double(app.calib_film(:,:,1));
             Red_norm= -log10(max(Red_channel, 1) ./ 65535);
             Green_channel = double(app.calib_film(:,:,2));
             Green_norm = -log10(max(Green_channel, 1) ./ 65535);
             Blue_channel = double(app.calib_film(:,:,3));
             Blue_norm = -log10(max(Blue_channel, 1) ./ 65535);

            switch app.Fit_type
                case 'Red'
                   corr = Red_norm ./Blue_norm;
                case 'Green'
                   corr = Green_norm ./Blue_norm;
            end

            %Fitting the data
            channel_corr = polyval(app.Initial_Fit,  corr); 
            app.calib_film = polyval(app.Final_Fit,  channel_corr);

            imshow(app.calib_film, 'Parent', app.UIAxes4);
            colormap(app.UIAxes4, app.ColMap);           

        end

        % Button pushed function: ManuallyAlignButton
        function ManuallyAlignButtonPushed(app, event)

            mbox = msgbox('Please select the first marker');
            waitfor(mbox);

            % Select the first marker
            point1 = drawcrosshair(app.UIAxes4,  'LineWidth', 0.25, 'Color', 'Red');
            marker1 = point1.Position;

            % Display the selected marker on the image
            hold(app.UIAxes4, 'on');
            plot(app.UIAxes4, marker1(1), marker1(2), 'LineWidth', 0.25,'Color', 'Red');
            
            mbox = msgbox('Please select the second marker');
            waitfor(mbox);

            % Manually select the second marker
            point2 = drawcrosshair(app.UIAxes4, 'LineWidth', 0.25, 'Color', 'Blue');
            marker2 = point2.Position;

            % Display the selected markers on the image
            plot(app.UIAxes4, marker2(1), marker2(2), 'LineWidth', 0.25, 'Color', 'Blue');
            hold(app.UIAxes4, 'off');

            % Calculate the angle of rotation
            angle = atan2(marker2(2) - marker1(2), marker2(1) - marker1(1)) * (180 / pi);

            % Rotate the image
            rotatedImg = imrotate(app.calib_film, angle, 'loose');
            app.calib_film = rotatedImg;
            imshow(app.calib_film, 'Parent', app.UIAxes4);


            if numel(size(app.calib_film)) >2
            else
                ContrastSliderValueChanged(app);
            end
            title(app.UIAxes4, '');
        end

        % Button pushed function: AutoAlignImageButton
        function AutoAlignImageButtonPushed(app, event)

            app.calib_film = fn_AutoImageAlign(app.calib_film);
            imshow(app.calib_film,'Parent', app.UIAxes4 );

            if numel(size(app.calib_film)) >2
            else
                ContrastSliderValueChanged(app);
            end
        end

        % Button pushed function: ROIDoseButton
        function ROIDoseButtonPushed(app, event)

            ROI = drawrectangle(app.UIAxes4, 'MarkerSize', 0.1);
            X1 = round(ROI.Position(1));
            Y1 = round(ROI.Position(2));
            X2 = round(X1 + ROI.Position(3) - 1);
            Y2 = round(Y1 + ROI.Position(4) - 1);


            Red_channel = mean2(app.calib_film(Y1:Y2, X1:X2));
            app.cGyEditField.Value = round(double(Red_channel), 2);

            % Create a label for the ROI
            label_text = [num2str(app.cGyEditField.Value), ' cGy'];
            label_x = X1 + 0.5 * ROI.Position(3); % X coordinate for label
            label_y = Y1 + 0.5 * ROI.Position(4); % Y coordinate for label
            text_handle = text(label_x, label_y, label_text, 'Parent', app.UIAxes4, 'Color', 'w', 'FontSize', 13, 'FontWeight', 'bold', 'EdgeColor', 'b');

            % Store the text handle in the ROI UserData for deletion later
            ROI.UserData = text_handle;

            % Set the buttondown function to allow deletion of text label
            set(text_handle, 'ButtonDownFcn', @(src, event) deleteText(app, src));

        end

        % Button pushed function: Button_20
        function Button_20Pushed(app, event)
            saving_film = round(app.calib_film);

            Date = datetime("today");

            film_info = {'Date: ', 'DPI: ', 'Interpolation: ','X Res: ','Y Res: '};
            film_data =  {Date, num2str(app.TiffDPI), num2str(app.EditField_5.Value), num2str(app.WidthCC.Value), num2str(app.HeightCC.Value)};           


            [filename, pathname] = uiputfile({'.txt'}, 'Please select', app.Path);          
            if filename ~= 0
                app.Path = pathname;          
                file_name = fullfile(pathname,filename);  

                txt_ID = fopen(file_name, 'w');
                if txt_ID == -1
                    return;
                end

                for i = 1: numel(film_info)
                    fprintf(txt_ID, '%s%s\n', film_info{i}, film_data{i});
                end
                
                fprintf(txt_ID, '\n');
                fprintf(txt_ID, '\n');
                fprintf(txt_ID, 'Array Start:');
                fprintf(txt_ID, '\n');
                f = waitbar(0, 'Saving the film dose!');
                for i = 1:size(saving_film, 1)
                    waitbar(i/size(saving_film, 2), f, 'Saving the film dose!');
                    fprintf(txt_ID,'%0.0f\t', saving_film(i,:)); 
                    fprintf(txt_ID, '\n');
                end

                waitbar(1, f, 'Done!'); 
                fprintf(txt_ID, '\n');
                fprintf(txt_ID, ':Array End');
                fclose(txt_ID);
            else
            end

        end

        % Button pushed function: FilmDoseButton
        function FilmDoseButtonPushed(app, event)
             [file, path] = uigetfile({'*.txt'}, 'Please select the film dose file', app.Path);
            text_file = fullfile(path, file);
             % Initialize data cell array
            data = {5};

            % Open the file
            ID = fopen(text_file, 'r');
            if ID == -1
                error('File cannot be opened');
            end
            
            % Read specific lines for values
            for i = 1:5
                line = fgetl(ID);
                parts = strsplit(line, ':');
                value = strtrim(parts{2});
                data{i, 1} = value;
            end
            
            % Parse read data into variables
            app.TiffDPI = str2double(data{2}) * str2double(data{3});
            app.Field_X = str2double(data{4});
            app.Field_Y = str2double(data{5});

            app.HeightCC.Value = app.Field_Y;
            app.WidthCC.Value = app.Field_X;
            
            % Reset file pointer to the beginning of the file to read matrix
            frewind(ID);            
            string1 = 'Array Start:';
            string2 = ':Array End';
            startReading = false;
            Matrix = [];


            f = waitbar(0, 'Opening the dose file!');
            % Read the file again to find the matrix
            while ~feof(ID)
                currentLine = fgetl(ID);
                if contains(currentLine, string1)
                    startReading = true;
                    continue; % Skip the line containing 'Array Start:'
                elseif contains(currentLine, string2)
                    break; % Stop reading if 'Array End' is found
                end

               
                if startReading
                    waitbar(0.5, f, 'Loading dose file!');    
                    numericData = str2num(currentLine);
                    Matrix = [Matrix; numericData];
                end
            end
            waitbar(1, f, 'Done!');
            close(f);

            % Close the file
            fclose(ID);            
            app.Film_dose = double(Matrix);
            % Show the image
            imshow(mat2gray(app.Film_dose), 'Parent', app.FigFilmDose);
            % Set colormap
            colormap(app.FigFilmDose, app.ColMap);
        end

        % Button pushed function: PlanDoseButton
        function PlanDoseButtonPushed(app, event)
            % Specify the folder containing DICOM files
            [file, path] = uigetfile({'*.dcm'}, 'Please select the film dose file', app.Path);

            if isequal(file,0)
                return;
            else
                dicomFolder = fullfile(path, file);
                app.Path = path;
            end


            TPS_info = dicominfo(dicomFolder);
            app.DicomInfo.PatientID = TPS_info.PatientID;
            app.Grid_scaling = TPS_info.DoseGridScaling;
            app.PixelSpacing_X = TPS_info.PixelSpacing(2);
            app.PixelSpacing_Y = TPS_info.PixelSpacing(1);
            app.Grid_scaling = TPS_info.DoseGridScaling;

            %Patient Info
            app.MRNEditField.Value =  app.DicomInfo.PatientID;


            % Read DICOM volume and information
            app.TPS_dose = double(squeeze(dicomread(dicomFolder)));

            app.NumRows = TPS_info.Rows;
            app.NumColumns = TPS_info.Columns;

            imshow(mat2gray(app.TPS_dose), 'Parent', app.FigDicomDose);
            colormap(app.FigDicomDose, app.ColMap);
            GraphTickMode(app, app.FigDicomDose, "TPS Dose");

            app.PlanPushed = 1;
            app.FxEditField.Value = app.fx;
            app.FxEditField.Editable = 'off';
            app.XShiftsmmEditField.Value = 0;
            app.YShiftsmmEditField.Value = 0;
            app.Yshift_counter = 0;

            if ~isempty(app.Film_dose)
                CompareProfileButtonPushed(app);
            end

        end

        % Button pushed function: ProfileButton
        function ProfileButtonPushed(app, event)

            if  app.FilmCheckBox.Value && app.TPSCheckBox.Value

                [app.xTPSPoint, app.yTPSPoint] = ProfilePoint(app, app.FigDicomDose);


                [app.xProfileTPS, app.yProfileTPS, app.x_dis_TPS, app.y_dis_TPS] = fn_DoseProfile(app.TPS_dose, app.xTPSPoint, app.yTPSPoint,'TPS', app.Grid_scaling, app.fx, app.DoseScaling);
                [app.xProfileFilm, app.yProfileFilm, app.x_dis_Film, app.y_dis_Film] = fn_DoseProfile( app.Film_dose,app.xTPSPoint, app.yTPSPoint, 'Film');


                %Plotting x profile for Film and TPS
                FilmPlot(app, app.UIAxes2, app.x_dis_Film, app.xProfileFilm );
                hold(app.UIAxes2, 'on');
                TPSPlot(app, app.UIAxes2,app.x_dis_TPS, app.xProfileTPS );
                hold(app.UIAxes2, 'off');
                legend(app.UIAxes2, 'Film', 'TPS');

                %Plotting y profile for film and TPS
                FilmPlot(app, app.UIAxes3,app.y_dis_Film, app.yProfileFilm);
                hold(app.UIAxes3, 'on');
                TPSPlot(app, app.UIAxes3,app.y_dis_TPS, app.yProfileTPS );
                hold(app.UIAxes3, 'off');
                legend(app.UIAxes3, 'Film', 'TPS');

            elseif app.TPSCheckBox.Value

                [app.xProfileTPS, app.yProfileTPS] = ProfilePoint(app, app.FigDicomDose);

                [app.xProfileTPS, app.yProfileTPS, app.x_dis_TPS, app.y_dis_TPS] = fn_DoseProfile(app.TPS_dose, app.xTPSPoint, app.yTPSPoint,'TPS', app.Grid_scaling, app.fx, app.DoseScaling);


                %Plotting x and y profile from TPS dose
                TPSPlot(app, app.UIAxes2, app.x_dis_TPS, app.xProfileTPS );
                TPSPlot(app, app.UIAxes3, app.y_dis_TPS, app.yProfileTPS);


            elseif app.FilmCheckBox.Value

                [app.xProfileFilm, app.yProfileFilm] = ProfilePoint(app, app.FigFilmDose);
                [app.xProfileFilm, app.yProfileFilm, app.x_dis_Film, app.y_dis_Film] = fn_DoseProfile( app.Film_dose,app.xTPSPoint, app.yTPSPoint, 'Film');

                %Plotting x and y profile from Film dose
                FilmPlot(app, app.UIAxes2,app.x_dis_Film, app.xProfileFilm);
                FilmPlot(app, app.UIAxes3,app.y_dis_Film, app.yProfileFilm);

            end

            app.FilmCheckBox.Value =false;
            app.TPSCheckBox.Value= false;

        end

        % Button pushed function: CompareProfileButton
        function CompareProfileButtonPushed(app, event)
           
            Filmdose = fn_MatrixSmooth(app.Film_dose, app.SmoothDropDown.Value, app.SmoothWIn.Value);    
            imshow(mat2gray(Filmdose), 'Parent', app.FigFilmDose);
            colormap(app.FigFilmDose, app.ColMap); 

            pixel_spacing = [app.PixelSpacing_X, app.PixelSpacing_Y];
             [app.xProfileTPS, app.yProfileTPS,  app.x_dis_TPS, app.y_dis_TPS, ...
                app.xProfileFilm, app.yProfileFilm, app.x_dis_Film, app.y_dis_Film]= fn_PhysicalDistancePlotter(app.TPS_dose, Filmdose,app.Grid_scaling,app.fx, ...
                app.DoseScaling,pixel_spacing, app.TiffDPI, app.UIAxes2, app.UIAxes3);


             if strcmp(app.DropDown_9.Value, 'Mean')
                 TPS_x = mean(app.xProfileTPS(:));
                 Film_x = mean(app.xProfileFilm(:));

                 TPS_y = mean(app.yProfileTPS(:));
                 Film_y = mean(app.yProfileFilm(:));
             elseif strcmp(app.DropDown_9.Value, 'Median')

                 TPS_x = median(app.xProfileTPS(:));
                 Film_x = median(app.xProfileFilm(:));
    
                 TPS_y = median(app.yProfileTPS(:));
                 Film_y = median(app.yProfileFilm(:));
             elseif strcmp(app.DropDown_9.Value, 'Max')
    
                 TPS_x = max(app.xProfileTPS(:));
                 Film_x = max(app.xProfileFilm(:));
    
                 %Y profile
                 TPS_y = max(app.yProfileTPS(:));
                 Film_y = max(app.yProfileFilm(:));
             end           
            
             %Y Diff
             Y_diff = (1 -TPS_y/Film_y) * 100;
             X_diff = (1 -TPS_x/Film_x) * 100;

             app.YDDEditField.Value = Y_diff;
             app.XDDEditField.Value = X_diff;

        end

        % Button pushed function: UpdateFilmDoseButton
        function UpdateFilmDoseButtonPushed(app, event)
            app.Film_dose = app.calib_film;
            imshow(mat2gray(app.Film_dose), 'Parent', app.FigFilmDose);
            colormap(app.FigFilmDose, app.ColMap);
            GraphTickMode(app, app.FigFilmDose, 'Film dose');
            app.Xshift_counter = 0; app.Yshift_counter = 0;

            app.Xshift_counter = 0; app.Yshift_counter = 0;
            app.XShiftsmmEditField.Value = 0;app.YShiftsmmEditField.Value = 0;

            app.DPIEditField.Value = app.TiffDPI;
        end

        % Button pushed function: UpdateDicomDoseButton
        function UpdateDicomDoseButtonPushed(app, event)
            prompt = {'Enter the number of fraction'};
            frac = inputdlg(prompt );
            app.fx = str2double(frac);
            if isempty(frac)
                msgbox("Need to input the number of fraction for correct dosimetry!");
                return;
            end

            app.PlaneInfo = app.DicomInfo;
            

            switch app.DropDown_3.Value
                case 'XZ'

                    app.TPS_dose = fn_PlaneCentering(app.xzPlane, app.ColumnEditField.Value,(double(app.NumFrames) - app.FrameEditField.Value));
                    %Subtracting CoEditfild by NumRows as the Z(frames) is read
                    %from top to bottom.

                    app.PlaneInfo.PixelSpacing(1) = app.PixelSpacing_X * 1/app.TPSInterpolation;
                    app.PlaneInfo.PixelSpacing(2) = app.SliceThickness * 1/app.TPSInterpolation;

                    PlaneSize = size(app.TPS_dose);
                    app.PlaneInfo.Rows = PlaneSize(2);
                    app.PlaneInfo.Columns = PlaneSize(1);
                    app.PlaneInfo.DoseGridScaling = app.DicomInfo.DoseGridScaling * 1/app.fx;
                    app.PlaneInfo.ImagePositionPatient = [app.XmmEditField.Value;app.YmmEditField.Value;app.ZmmEditField.Value ];
            
                    imshow(mat2gray(app.TPS_dose), 'Parent', app.FigDicomDose);
                    colormap(app.FigDicomDose, app.ColMap);
                    GraphTickMode(app, app.FigDicomDose, "TPS Dose");

                case 'YZ'
                    app.TPS_dose = fn_PlaneCentering(app.yzPlane,(double(app.NumRows) -app.RowEditField.Value) ,(double(app.NumFrames) - app.FrameEditField.Value));
                    %Subtracting RoEditFiled by NumRows as the plane was
                    %rotated was flipped

                    app.PlaneInfo.PixelSpacing(1) = app.PixelSpacing_Y * 1/app.TPSInterpolation;
                    app.PlaneInfo.PixelSpacing(2) = app.SliceThickness * 1/app.TPSInterpolation;
                    
                    PlaneSize = size(app.TPS_dose);
                    app.PlaneInfo.Rows = PlaneSize(2);
                    app.PlaneInfo.Columns = PlaneSize(1);
                    app.PlaneInfo.DoseGridScaling = app.DicomInfo.DoseGridScaling * 1/app.fx;
                    app.PlaneInfo.ImagePositionPatient = [app.XmmEditField.Value;app.YmmEditField.Value;app.ZmmEditField.Value ];


                    imshow(mat2gray((app.TPS_dose)), 'Parent', app.FigDicomDose);
                    colormap(app.FigDicomDose, app.ColMap);
                    GraphTickMode(app, app.FigDicomDose, "TPS Dose");

                case 'XY'
                    app.TPS_dose = fn_PlaneCentering(app.xyPlane, app.ColumnEditField.Value, app.RowEditField.Value);

                    app.PlaneInfo.PixelSpacing(1) = app.PixelSpacing_X* 1/app.TPSInterpolation;
                    app.PlaneInfo.PixelSpacing(2) = app.PixelSpacing_Y* 1/app.TPSInterpolation;

                    PlaneSize = size(app.TPS_dose);
                    app.PlaneInfo.Rows = PlaneSize(2);
                    app.PlaneInfo.Columns = PlaneSize(1);
                    app.PlaneInfo.DoseGridScaling = app.DicomInfo.DoseGridScaling * 1/app.fx;
                    app.PlaneInfo.ImagePositionPatient =  [app.XmmEditField.Value;app.XmmEditField.Value;app.YmmEditField.Value ];


                    imshow(mat2gray((app.TPS_dose)), 'Parent', app.FigDicomDose);
                    colormap(app.FigDicomDose, app.ColMap);
                    GraphTickMode(app, app.FigDicomDose, "TPS Dose");

            end

            app.Xshift_counter = 0; app.Yshift_counter = 0;
            app.XShiftsmmEditField.Value = 0;app.YShiftsmmEditField.Value = 0;

            app.FxEditField.Value = app.fx;

        end

        % Button pushed function: FilterNoiseButton
        function FilterNoiseButtonPushed(app, event)
            app.calib_film(isnan(app.calib_film) | isinf(app.calib_film) | abs(app.calib_film) >app.FilterNoiseEditField.Value) = 1 ;
            imshow(app.calib_film, 'Parent', app.UIAxes4);
            colormap(app.UIAxes4, app.ColMap);
            if numel(size(app.calib_film)) >2
            else
                ContrastSliderValueChanged(app);
            end

        end

        % Button pushed function: ScaleTPSButton
        function ScaleTPSButtonPushed(app, event)
            app.fx = double(app.FxEditField.Value);
            app.DoseScaling = double(app.ScaleVal.Value);
            app.DoseScaledEditField.Value = (app.DoseScaling -1) * 100;
            CompareProfileButtonPushed(app);
            %GammaEvalButtonPushed(app);
        end

        % Button pushed function: MedianFilterButton
        function MedianFilterButtonPushed(app, event)
            if numel(size(app.calib_film)) > 2
                R = app.calib_film(:, :, 1);
                G = app.calib_film(:, :, 2);
                B = app.calib_film(:, :, 3);

                % Apply median filter to each channel
                R_filtered = medfilt2(R, [app.window_size app.window_size]);
                G_filtered = medfilt2(G, [app.window_size app.window_size]);
                B_filtered = medfilt2(B, [app.window_size app.window_size]);

                % Combine the channels back into one image
                new_Image = cat(3, R_filtered, G_filtered, B_filtered);
            else
                new_Image = medfilt2(app.calib_film, [app.window_size app.window_size]);
            end

            % Update the image display
            app.calib_film = new_Image;
            imshow(mat2gray(app.calib_film), 'Parent', app.UIAxes4);
            colormap(app.UIAxes4, app.ColMap);

            if numel(size(app.calib_film)) >2
            else
                ContrastSliderValueChanged(app);
            end

        end

        % Value changed function: FilmSlider
        function FilmSliderValueChanged2(app, event)
            sliderValue = app.FilmSlider.Value; % Get the current value of the slider
            if ~isempty(app.Film_dose)
                app.FilmSlider.Limits = [ 0, 20];
                adjustedFilmDose = mat2gray(app.Film_dose );
                adjustedFilmDose = adjustedFilmDose * sliderValue;
                imshow((adjustedFilmDose), 'Parent', app.FigFilmDose);
                colormap(app.FigFilmDose, app.ColMap);
                GraphTickMode(app, app.FigFilmDose, "Film Dose");
            end

        end

        % Value changed function: TPSSlider
        function TPSSliderValueChanged(app, event)
            sliderValue = app.TPSSlider.Value;
            if ~isempty(app.TPS_dose)
                app.TPSSlider.Limits = [ 0, 20];
                adjustedTPSDose = mat2gray(app.TPS_dose);
                adjustedTPSDose = adjustedTPSDose * sliderValue;
                imshow((adjustedTPSDose), 'Parent', app.FigDicomDose);
                colormap(app.FigDicomDose, app.ColMap);
                GraphTickMode(app, app.FigDicomDose, "TPS Dose");
            end
        end

        % Value changed function: ContrastSlider
        function ContrastSliderValueChanged(app, event)
            sliderValue = app.ContrastSlider.Value; % Get the current value of the slider
            if ~isempty(app.calib_film)
                app.ContrastSlider.Limits = [0 mean(app.calib_film(:))];
                adjustedFilmDose = mat2gray(app.calib_film );
                adjustedFilmDose = adjustedFilmDose * sliderValue ;
                imshow((adjustedFilmDose), 'Parent', app.UIAxes4);
                colormap(app.UIAxes4, app.ColMap);
            end

        end

        % Button pushed function: FixFilmResButton
        function FixFilmResButtonPushed(app, event)
            % Calculate physical dimensions for Film
            DPI = double(app.DPIEditField.Value);
            [Film_row, Film_col] = size(app.Film_dose);


            %Calculting the DPI of the film dose
            Film_x_cm = Film_row * 2.54 / DPI;
            Film_y_cm = Film_col * 2.54 / DPI;

            Pixel_spacing = [app.PixelSpacing_X, app.PixelSpacing_Y];

            [TPS_row, TPS_col] = size(app.TPS_dose);
            TPS_x_cm = TPS_col * Pixel_spacing(1) * 0.1;
            TPS_y_cm = TPS_row * Pixel_spacing(2) * 0.1;

            Film_pixel_x = Film_x_cm / Film_row;
            Film_pixel_y = Film_y_cm / Film_col;

            TPS_pixel_x = TPS_x_cm / TPS_col;
            TPS_pixel_y = TPS_y_cm / TPS_row;


            % Calculate scaling factors for each axis
            scaling_factor_x = Film_pixel_x / TPS_pixel_x;
            scaling_factor_y = Film_pixel_y / TPS_pixel_y;

            % Resize TPS matrix for each axis separately
            % Calculate new size based on separate scaling factors
            new_rows = round(size(app.Film_dose, 1) * scaling_factor_y);
            new_cols = round(size(app.Film_dose, 2) * scaling_factor_x);
            resized_Film_dose = imresize(app.Film_dose, [new_rows, new_cols]);

            app.Film_dose_gamma = double(resized_Film_dose);

        end

        % Button pushed function: LocateCenterButton
        function LocateCenterButtonPushed(app, event)
            switch app.DropDown_2.Value
                case 'Film'
                    [x, y] = ProfilePoint(app, app.FigFilmDose);
                    app.Film_Center = [x, y];
                    imageSize = size(app.Film_dose);
                    desiredCenter = [imageSize(2)/2, imageSize(1)/2]; % [x, y]
                    translationVector = desiredCenter - app.Film_Center;

                    Film_centered = imtranslate(app.Film_dose, translationVector);
                    app.Film_dose = Film_centered;
                    imshow(mat2gray(app.Film_dose), 'Parent', app.FigFilmDose);
                    colormap(app.FigFilmDose, app.ColMap);
                    app.FilmSlider.Value = app.TPSSlider.Value * app.DoseScaling;
                case 'TPS'
                    [x, y] = ProfilePoint(app, app.FigDicomDose);
                    app.TPS_Center = [x, y];
                    imageSize = size(app.TPS_dose);
                    desiredCenter = [imageSize(2)/2, imageSize(1)/2]; % [x, y]
                    translationVector = desiredCenter - app.TPS_Center;
                    TPS_centered = imtranslate(app.TPS_dose, translationVector);
                    app.TPS_dose = TPS_centered;
                    imshow(mat2gray(app.TPS_dose), 'Parent', app.FigDicomDose);
                    colormap(app.FigDicomDose, app.ColMap);
            end
        end

        % Button pushed function: RDDoseButton
        function RDDoseButtonPushed(app, event)
            app.DicomVolume = [];
            app.RotateDicomVolume = [];

            [file,path] = uigetfile({'*.dcm'}, 'Please select the TPS dose file', app.Path);

            if isequal(file,0)
                return;
            else
                app.Path = path;
                Dicom_file = fullfile(path, file);

                prompt = {'Selected file: ', 'Interp Method: (linear, nearest, cubic)', 'Grid: ' };
                dlgtitle = 'RTDose Import';
                fieldsize = [1 50; 1 40; 1 5];
                definput = {Dicom_file , 'linear', '1' };
                answer = inputdlg(prompt,dlgtitle,fieldsize,definput);
                if isempty(answer)
                    return;
                end
            end

            app.DicomInfo = dicominfo(Dicom_file);
            original_volume = dicomread(app.DicomInfo);
            app.DicomVolume = fn_VolumeInterp(app.DicomInfo, answer{2}, str2double(answer{3}));
            DcmVol_size = size(app.DicomVolume);
            app.TPSInterpolation = str2double(answer{3});
            
            %Patient Info
            app.MRNEditField.Value =  app.DicomInfo.PatientID;

            % Image properties
            app.PixelSpacing_Y = app.DicomInfo.PixelSpacing(1);
            app.PixelSpacing_X = app.DicomInfo.PixelSpacing(2);
            if isempty(app.DicomInfo.SliceThickness)
                app.SliceThickness = app.DicomInfo.PixelSpacing(1);
            end

            if ~isfield(app.DicomInfo ,'DoseGridScaling')
                app.Grid_scaling = 1;
            else
                app.Grid_scaling = app.DicomInfo.DoseGridScaling;
            end

            if ~isfield(app.DicomInfo, 'ImagePositionPatient')
                app.ImagePP = [0;0;0]; 
            else
                app.ImagePP = app.DicomInfo.ImagePositionPatient(:);
            end
            
            if ~isfield(app.DicomInfo, 'ImageOrientationPatient')
                app.ImageOrent = [1;0;0;0;1;0];
            else
                app.ImageOrent = app.DicomInfo.ImageOrientationPatient(:);
            end

            % Volume dimensions
            app.NumRows = DcmVol_size(1);
            app.NumColumns = DcmVol_size(2);
            app.NumFrames = DcmVol_size(3);

            if ~isfield(app.DicomInfo, 'Manufacturer')
                app.SystemLabel.Text = 'Unknown';
            else
                app.SystemLabel.Text = app.DicomInfo.Manufacturer;
            end
            

            PixelSpacing = [app.PixelSpacing_X app.PixelSpacing_Y app.SliceThickness];
            [app.X_dicom_cm, app.Y_dicom_cm, app.Z_dicom_cm] = fn_REF3DExtent(original_volume, app.ImagePP, app.ImageOrent, PixelSpacing);            

            app.XmmEditField.Value = double(round(mean(app.X_dicom_cm(:)), 2));
            app.YmmEditField.Value = double(round(mean(app.Y_dicom_cm(:)), 2));
            app.ZmmEditField.Value = double(round(mean(app.Z_dicom_cm(:)), 2));

            app.EditField_6.Value = (app.X_dicom_cm(1));
            app.EditField_7.Value = (app.X_dicom_cm(2));
            app.EditField_8.Value = (app.Y_dicom_cm(1));
            app.EditField_9.Value = (app.Y_dicom_cm(2));
            app.EditField_10.Value = (app.Z_dicom_cm(1));
            app.EditField_11.Value = (app.Z_dicom_cm(2));
            
            app.VolRotStatus = 'false';
            app.CalculateButtonPushed(app);
            
            % Button_9Pushed(app, event);

            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            if strcmp(app.VolRotStatus, 'true')
                Volume = app.RotateDicomVolume;                
            else 
                Volume = app.DicomVolume;
            end

            New_size = size(Volume);
            app.YZSlider.Limits = [1, double(New_size(2))];
            app.XZSlider.Limits = [1, double(New_size(1))];
            app.XYSlider.Limits= [1, double(New_size(3))];

            app.ColumnEditField.Value = abs(round(fn_IPPNormalizer(double(app.XmmEditField.Value),  double(app.X_dicom_cm), double([0 app.NumColumns]))));
            app.RowEditField.Value = abs(round(fn_IPPNormalizer(double(app.YmmEditField.Value), double(app.Y_dicom_cm), double([0 app.NumRows]))));
            app.FrameEditField.Value = abs(round(fn_IPPNormalizer(double(app.ZmmEditField.Value),  double(app.Z_dicom_cm),double([0 app.NumFrames]))));

            app.yzPlane = fn_PlaneNavigator(Volume, 'YZ', app.ColumnEditField.Value, app.YZFigure ,app.PixelSpacing_Y, app.SliceThickness,  app.ColMap);
            app.xzPlane = fn_PlaneNavigator(Volume, 'XZ', app.RowEditField.Value, app.XZFigure, app.PixelSpacing_X, app.SliceThickness,  app.ColMap);
            app.xyPlane = fn_PlaneNavigator(Volume, 'XY', app.FrameEditField.Value, app.XYFigure, app.PixelSpacing_X, app.PixelSpacing_Y,  app.ColMap);

            app.YZSlider.Value =(app.ColumnEditField.Value);
            app.XZSlider.Value = (app.RowEditField.Value);
            app.XYSlider.Value =(app.FrameEditField.Value);

        end

        % Button pushed function: RotateButton
        function RotateButtonPushed(app, event)
           Pitch = app.PitchEditField.Value;
           Yaw = app.YawEditField.Value;
           Roll =  app.RollEditField.Value;

           if Pitch == 0
               Pitch = Pitch - app.Post_pitch;
           end           
           if Yaw == 0
               Yaw = Yaw - app.Post_yaw;
           end
           if Roll == 0
               Roll = Roll - app.Post_roll;
           end
           
            if strcmp(app.VolRotStatus, 'true')
                Volume = app.RotateDicomVolume;                
            else 
                Volume = (app.DicomVolume);
            end
            app.new_center = [app.ColumnEditField.Value, app.RowEditField.Value, app.FrameEditField.Value];
            app.RotateDicomVolume =  Volume;  

            app.RotateDicomVolume= fn_DicomVRotate_Adv(app.RotateDicomVolume, 'Pitch', Pitch,  app.new_center);
            app.RotateDicomVolume = fn_DicomVRotate_Adv(app.RotateDicomVolume, 'Yaw', Yaw,  app.new_center);
            app.RotateDicomVolume= fn_DicomVRotate_Adv(app.RotateDicomVolume, 'Roll',Roll,  app.new_center);

            app.Post_pitch = Pitch;
            app.Post_yaw = Yaw;
            app.Post_roll = Roll;

            app.VolRotStatus = 'true';
           
            CalculateButtonPushed(app);
        end

        % Button pushed function: RotatedDoseButton
        function RotatedDoseButtonPushed(app, event)
            app.saveVolume = app.RotateDicomVolume;
            app.DicomInfo.ImagePositionPatient = app.ImagePP;            

            [filename, pathname] = uiputfile({'*.dcm'}, 'Please select', app.Path);
            if filename ~= 0
                app.Path = path;
                [rows, cols, frames] = size(app.saveVolume);
                file_name = fullfile(pathname, filename);
                rotatedVolume = reshape(app.saveVolume, rows, cols, 1, frames);
                try
                    dicomwrite(rotatedVolume,file_name,app.DicomInfo, 'CreateMode', 'copy' );
                catch
                end
            else
                return;
            end
            msgbox('Export sucessful!');
        end

        % Button pushed function: PlanFilmButton
        function PlanFilmButtonPushed(app, event)
            if strcmp(app.GridDropDown.Value, 'Coarse')
                FixFilmResButtonPushed(app);
                reference = double(app.TPS_dose) * app.Grid_scaling * 100 * (1/app.fx )* app.DoseScaling;
               
                Target = fn_MatrixSmooth(app.Film_dose_gamma, app.SmoothDropDown.Value, app.SmoothWIn.Value);   
            elseif strcmp(app.GridDropDown.Value, 'Fine')
                DPI = app.TiffDPI;
                Pixel_spacing = [app.PixelSpacing_X, app.PixelSpacing_Y];
                [reference, Target] = fn_ResolutionFixer(DPI, app.TPS_dose,app.Film_dose, Pixel_spacing);    
                Target = fn_MatrixSmooth(Target, app.SmoothDropDown.Value, app.SmoothWIn.Value);   
                reference = double(reference) * app.Grid_scaling * 100 * (1/app.fx )* app.DoseScaling;
            end
            
        % Get the size of reference and Target
        ref_size = size(reference);
        target_size = size(Target);
        
        % Calculate the center position to align Target in reference
        center_pos = floor((ref_size - target_size) / 2);
        
        % Create a zero matrix of the same size as reference
        ref_array = zeros(ref_size);
        
        % Align Target at the center of ref_array
        ref_array(center_pos(1)+1:center_pos(1)+target_size(1), center_pos(2)+1:center_pos(2)+target_size(2)) = Target;
        
        % Perform subtraction
        Sub_Dose = ref_array - reference;
        
        % Display the result
        imshow(Sub_Dose, 'Parent', app.UIAxes6);
        colormap(app.UIAxes6, app.ColMap);

        end

        % Button pushed function: GammaEvalButton
        function GammaEvalButtonPushed(app, event)

            DD = app.DDEditField.Value;
            DTA = app.DTAmmEditField.Value;
            Type = app.DropDown_8.Value;
            Pixel_size = app.PixelSpacing_X;
            WindowSize = app.ResEditField.Value;
            GammaType = app.ButtonGroup.SelectedObject.Text;
            PercentSignal = app.SingalEditField.Value; 
            
            if strcmp(app.GridDropDown.Value, 'Coarse')
                FixFilmResButtonPushed(app);
                reference = double(app.TPS_dose) * app.Grid_scaling * 100 * (1/app.fx )* app.DoseScaling;
                Target = fn_MatrixSmooth(app.Film_dose_gamma, app.SmoothDropDown.Value, app.SmoothWIn.Value);   
                [app.gammamap, pass] =  fn_SimpleGammaIndex(reference,Target ,Type , GammaType, WindowSize, DTA, DD, Pixel_size, PercentSignal);
            elseif strcmp(app.GridDropDown.Value, 'Fine')
                answer = questdlg('"Fine" Gamma selected, Do you still want to proceed?', ...
                    'Yes', 'No');
                if strcmp(answer, 'Yes') 
                    DPI = app.TiffDPI;
                    Pixel_spacing = [app.PixelSpacing_X, app.PixelSpacing_Y];
                    [reference, Target] = fn_ResolutionFixer(DPI, app.TPS_dose,app.Film_dose, Pixel_spacing);    
                    Target = fn_MatrixSmooth(Target, app.SmoothDropDown.Value, app.SmoothWIn.Value);   
                    reference = double(reference) * app.Grid_scaling * 100 * (1/app.fx )* app.DoseScaling;
                    [app.gammamap, pass] =  fn_SimpleGammaIndex(reference,Target ,Type , GammaType, WindowSize, DTA, DD, Pixel_size, PercentSignal);
                else
                    return;
                end
            end

            %app.gammamap = interp2(app.gammamap, 2, 'spline');
            % Display the gamma map
            imshow(app.gammamap, 'Parent', app.UIAxes6);
            colormap(app.UIAxes6, app.ColMap);
        
            %Plotting the gamma histogram 
            histogram(app.UIAxes11,app.gammamap);


            app.EditField_3.Value = double(DD);
            app.EditField2.Value = double(DTA);
            app.EditField_4.Value = double(pass);
            if double(pass) < 90
                app.EditField_4.BackgroundColor = 'Red';
                app.EditField_4.FontColor = 'Black';

            else
                app.EditField_4.BackgroundColor = 'Green';
                app.EditField_4.FontColor = 'Black';

            end
    

        end

        % Value changed function: RotatedPlane
        function RotatedPlaneValueChanged(app, event)
             switch app.RotatedPlane.Value
                case 'YZ'
                    yzPla = fn_PlaneNavigator(app.RotateDicomVolume,'YZ',app.YZSlider.Value, app.RotatedFigure, app.PixelSpacing_Y, app.SliceThickness,  app.ColMap);

                    [~, linearIndex] = max(yzPla(:));
                    [row, col] = ind2sub(size(yzPla), linearIndex);
                    app.Label_11.Text = mat2str(row);
                    app.Label_12.Text = mat2str(col);
                case 'XZ'

                    xzPla = fn_PlaneNavigator(app.RotateDicomVolume,'XZ',app.XZSlider.Value, app.RotatedFigure, app.PixelSpacing_X, app.SliceThickness,  app.ColMap);

                    [~, linearIndex] = max(xzPla(:));
                    [row, col] = ind2sub(size(xzPla), linearIndex);
                    app.Label_11.Text = mat2str(row);
                    app.Label_12.Text = mat2str(col);
                case 'XY'

                    xyPla = fn_PlaneNavigator(app.RotateDicomVolume,'XY',app.XYSlider.Value, app.RotatedFigure, app.PixelSpacing_X, app.PixelSpacing_Y,  app.ColMap);

                    [~, linearIndex] = max(xyPla(:));
                    [row, col] = ind2sub(size(app.xyPlane), linearIndex);
                    app.Label_11.Text = mat2str(row);
                    app.Label_12.Text = mat2str(col);
            end


        end

        % Value changed function: OGPlane
        function OGPlaneValueChanged(app, event)
            switch app.OGPlane.Value
                case 'YZ'
                    yzPla = fn_PlaneNavigator(app.DicomVolume,'YZ',app.YZSlider.Value,  app.OriginalFigure, app.PixelSpacing_Y, app.SliceThickness,  app.ColMap);

                    [~, linearIndex] = max(yzPla(:));
                    [row, col] = ind2sub(size(yzPla), linearIndex);
                    app.Label_9.Text = mat2str(row);
                    app.Label_10.Text = mat2str(col);

                case 'XZ'

                    xzPla = fn_PlaneNavigator(app.DicomVolume,'XZ',app.XZSlider.Value,  app.OriginalFigure, app.PixelSpacing_X, app.SliceThickness,  app.ColMap);

                    [~, linearIndex] = max(xzPla(:));
                    [row, col] = ind2sub(size(xzPla), linearIndex);
                    app.Label_9.Text = mat2str(row);
                    app.Label_10.Text = mat2str(col);
                case 'XY'
                    xyPla = fn_PlaneNavigator(app.DicomVolume,'XY',app.XYSlider.Value,  app.OriginalFigure, app.PixelSpacing_X, app.PixelSpacing_Y,  app.ColMap);

                    [~, linearIndex] = max(xyPla(:));
                    [row, col] = ind2sub(size(xyPla), linearIndex);
                    app.Label_9.Text = mat2str(row);
                    app.Label_10.Text = mat2str(col);
            end

        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            switch app.RotatedPlane.Value
                case 'YZ'
                    app.RotateDicomVolume = imtranslate(app.RotateDicomVolume, [0, 0,  round(app.StepsizepixelEditField.Value)]);
                    app.RotatedPlaneValueChanged(app);
                case 'XZ'
                    app.RotateDicomVolume = imtranslate(app.RotateDicomVolume, [0 , 0, round(app.StepsizepixelEditField.Value)]);
                    app.RotatedPlaneValueChanged(app);
                case 'XY'
                    app.RotateDicomVolume = imtranslate(app.RotateDicomVolume, [0,  -round(app.StepsizepixelEditField.Value), 0]);
                    app.RotatedPlaneValueChanged(app);
            end


        end

        % Button pushed function: vButton
        function vButtonPushed(app, event)
            switch app.RotatedPlane.Value
                case 'YZ'
                    app.RotateDicomVolume = imtranslate(app.RotateDicomVolume, [0, 0,  -round(app.StepsizepixelEditField.Value)]);
                    app.RotatedPlaneValueChanged(app);
                case 'XZ'
                    app.RotateDicomVolume = imtranslate(app.RotateDicomVolume, [0 , 0, -round(app.StepsizepixelEditField.Value)]);
                    app.RotatedPlaneValueChanged(app);
                case 'XY'
                    app.RotateDicomVolume = imtranslate(app.RotateDicomVolume, [0,  round(app.StepsizepixelEditField.Value), 0]);
                    app.RotatedPlaneValueChanged(app);
            end

        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            switch app.RotatedPlane.Value
                case 'YZ'
                    app.RotateDicomVolume = imtranslate(app.RotateDicomVolume, [0,  -round(app.StepsizepixelEditField.Value, 0)]);
                    app.RotatedPlaneValueChanged(app);
                case 'XZ'
                    app.RotateDicomVolume = imtranslate(app.RotateDicomVolume, [ round(app.StepsizepixelEditField.Value), 0,0]);
                    app.RotatedPlaneValueChanged(app);
                case 'XY'
                    app.RotateDicomVolume = imtranslate(app.RotateDicomVolume, [round(app.StepsizepixelEditField.Value), 0, 0]);
                    app.RotatedPlaneValueChanged(app);

            end
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            switch app.RotatedPlane.Value
                case 'YZ'
                    app.RotateDicomVolume = imtranslate(app.RotateDicomVolume, [0,  round(app.StepsizepixelEditField.Value, 0)]);
                    app.RotatedPlaneValueChanged(app);
                case 'XZ'
                    app.RotateDicomVolume = imtranslate(app.RotateDicomVolume, [ -round(app.StepsizepixelEditField.Value), 0,0]);
                    app.RotatedPlaneValueChanged(app);
                case 'XY'
                    app.RotateDicomVolume = imtranslate(app.RotateDicomVolume, [  -round(app.StepsizepixelEditField.Value), 0,0]);
                    app.RotatedPlaneValueChanged(app);

            end
        end

        % Button pushed function: FilmProfileButton
        function FilmProfileButtonPushed(app, event)
            film_size = size(app.calib_film);

            center = app.ButtonGroup_2.SelectedObject.Text;
            if strcmp(center, 'Center')
                app.x_pro = round(film_size(2) * 0.5);
                app.y_pro = round(film_size(1) * 0.5);
            else
                [app.x_pro, app.y_pro] =ProfilePoint(app, app.UIAxes4);
            end

            [x_profile, y_profile, dis_x, dis_y] = fn_DoseProfile(app.calib_film, app.x_pro, app.y_pro, 'Film');

            Dose_Unit = 'Dose (cGy)';
            if app.NormalizeCheckBox.Value
                %Normaling X and Y profile
                x_max = max(x_profile(:)); x_min = min(x_profile(:));
                y_max = max(y_profile(:)); y_min = min(x_profile(:));
                x_profile = (x_profile - x_min) /(x_max - x_min) * 100;
                y_profile = (y_profile - y_min) /(y_max - y_min) * 100;
                Dose_Unit = 'Dose (%)';
            end
            
            dis_x = dis_x .* (2.54/app.TiffDPI);
            dis_y = dis_y .* (2.54/app.TiffDPI);
            app.combinedX = [dis_x(:), x_profile(:)];
            app.combinedY = [dis_y(:), y_profile(:)];

            plot(app.UIAxes9,dis_x, x_profile);
            app.UIAxes9.YLabel.String = Dose_Unit;
            app.UIAxes9.XLabel.String = 'Distance (cm)';
            app.UIAxes9.XTickMode = 'auto';
            app.UIAxes9.YTickMode = 'auto';


            plot(app.UIAxes9_2,dis_y, y_profile);
            app.UIAxes9_2.YLabel.String = Dose_Unit;
            app.UIAxes9_2.XLabel.String = 'Distance (cm)';
            app.UIAxes9_2.XTickMode = 'auto';
            app.UIAxes9_2.YTickMode = 'auto';


        end

        % Button pushed function: ManualCropButton
        function ManualCropButtonPushed(app, event)
            rectangle = drawrectangle(app.UIAxes4);
            app.calib_film = imcrop((app.calib_film), rectangle.Position );
            new_size = size(app.calib_film);
            app.HeightCC.Value = new_size(1);
            app.WidthCC.Value = new_size(2);
            
            imshow(app.calib_film, 'Parent',app.UIAxes4);
            colormap(app.UIAxes4, app.ColMap);

            if numel(size(app.calib_film)) >2
            else
                ContrastSliderValueChanged(app);
            end


        end

        % Button pushed function: UpButton_2
        function UpButton_2Pushed(app, event)
            app.Film_dose = imtranslate(app.Film_dose, [0, -(app.StepsizepixelEditField_2.Value)], 'OutputView','full', 'FillValues',1);


            imshow(app.Film_dose, [], 'Parent', app.FigFilmDose);
            colormap(app.FigFilmDose, app.ColMap);
            GraphTickMode(app, app.FigFilmDose, 'Film dose');
            app.FilmSlider.Value = app.TPSSlider.Value * app.DoseScaling;


            CompareProfileButtonPushed(app);
            if app.PerformGammaCheckBox.Value == 1
                GammaEvalButtonPushed(app);
            end

            X_ini = (app.YShiftsmmEditField.Value);
            X_ini = X_ini + ((app.StepsizepixelEditField_2.Value) * app.PixelSpacing_X(1));
            app.YShiftsmmEditField.Value = X_ini;

        end

        % Button pushed function: DownButton_2
        function DownButton_2Pushed(app, event)
            app.Film_dose = imtranslate(app.Film_dose, [0, (app.StepsizepixelEditField_2.Value)], 'OutputView','full', 'FillValues',1);

            imshow(app.Film_dose,[], 'Parent', app.FigFilmDose);
            colormap(app.FigFilmDose, app.ColMap);
            GraphTickMode(app, app.FigFilmDose, 'Film dose');
            app.FilmSlider.Value = app.TPSSlider.Value * app.DoseScaling;

            CompareProfileButtonPushed(app);

            if app.PerformGammaCheckBox.Value == 1
                GammaEvalButtonPushed(app);
            end

            X_ini = (app.YShiftsmmEditField.Value);
            X_ini = X_ini - ((app.StepsizepixelEditField_2.Value) * app.PixelSpacing_X(1));
            app.YShiftsmmEditField.Value = X_ini;

        end

        % Button pushed function: LeftButton_2
        function LeftButton_2Pushed(app, event)
            app.Film_dose = imtranslate(app.Film_dose, [-(app.StepsizepixelEditField_2.Value), 0], 'OutputView','full', 'FillValues',1);

            imshow(app.Film_dose,[], 'Parent', app.FigFilmDose);
            colormap(app.FigFilmDose, app.ColMap);
            GraphTickMode(app, app.FigFilmDose, 'Film dose');
            app.FilmSlider.Value = app.TPSSlider.Value * app.DoseScaling;

            CompareProfileButtonPushed(app);

            if app.PerformGammaCheckBox.Value == 1
                GammaEvalButtonPushed(app);
            end

            X_ini = (app.XShiftsmmEditField.Value);
            X_ini = X_ini - ((app.StepsizepixelEditField_2.Value) * app.PixelSpacing_X(1));
            app.XShiftsmmEditField.Value = X_ini;

        end

        % Button pushed function: RightButton_2
        function RightButton_2Pushed(app, event)
            app.Film_dose = imtranslate(app.Film_dose, [(app.StepsizepixelEditField_2.Value), 0], 'OutputView','full', 'FillValues',1);

            imshow(app.Film_dose,[], 'Parent', app.FigFilmDose);
            colormap(app.FigFilmDose, app.ColMap);
            GraphTickMode(app, app.FigFilmDose, 'Film dose');
            app.FilmSlider.Value = app.TPSSlider.Value * app.DoseScaling;

            CompareProfileButtonPushed(app);

            if app.PerformGammaCheckBox.Value == 1
                GammaEvalButtonPushed(app);
            end

            X_ini = (app.XShiftsmmEditField.Value);
            X_ini = X_ini + ((app.StepsizepixelEditField_2.Value) * app.PixelSpacing_X(1));
            app.XShiftsmmEditField.Value = X_ini;

        end

        % Button pushed function: InterpolationButton
        function InterpolationButtonPushed(app, event)

            if numel(size(app.calib_film)) >2
                msgbox("Please load dose file");
            else
                app.calib_film = fn_DoseSampling(app.calib_film, app.EditField_5.Value, app.DropDown_7.Value);
                imshow(app.calib_film, 'Parent', app.UIAxes4);
                colormap(app.UIAxes4, app.ColMap);
                ContrastSliderValueChanged(app);
            end


        end

        % Button pushed function: Button_19
        function Button_19Pushed(app, event)
            % Extract film profiles
            [x_profile, y_profile] = fn_DoseProfile(app.calib_film, app.x_pro, app.y_pro, 'Film', 1, 1, 1);

            dis_X = numel(x_profile) *2.54/ app.TiffDPI;
            dis_x = linspace(-dis_X * 0.5, dis_X * 0.5, numel(x_profile)); % Normalize the range to [0, dis_X]

            % Calculate distance range for y_profile
            dis_Y = numel(y_profile) *2.54/ app.TiffDPI;
            dis_y = linspace(-dis_Y * 0.5, dis_Y * 0.5, numel(y_profile)); % Normalize the range to [0, dis_Y]

            % Construct matrices for export
            X_mat = [dis_x(:), x_profile(:)];
            Y_mat = [dis_y(:), y_profile(:)];

            % Prompt user to select a location to save the data
            [filename, pathname] = uiputfile({'*.txt'}, 'Please select', app.Path);

            % Check if user selected a location
            if filename ~= 0
                app.Path = pathname;

                % Construct file names
                X_file_name = ['XProfile_', filename];
                Y_file_name = ['YProfile_', filename];

                % Write data to files
                writematrix(X_mat, fullfile(pathname, X_file_name));
                writematrix(Y_mat, fullfile(pathname, Y_file_name));
            else
                % If export is canceled
                msgbox('Export canceled!');
            end

        end

        % Value changing function: YZSlider
        function YZSliderValueChanging(app, event)
            app.slider_current1 = round(event.Value);
            app.ColumnEditField.Value = app.slider_current1;
            app.XmmEditField.Value = fn_InverseIPPNormalizer(double(app.ColumnEditField.Value) ,double(app.X_dicom_cm), double([0 app.NumColumns]) );
            if strcmp(app.VolRotStatus, 'false')
                volume = app.DicomVolume;
            else
                volume = app.RotateDicomVolume;
            end
            app.yzPlane = fn_PlaneNavigator(volume, 'YZ', app.slider_current1, app.YZFigure, app.PixelSpacing_Y, app.SliceThickness, app.ColMap);
            
            if app.MaxPlaneDoseCheckBox.Value == 1
                max_dose = max(double(app.yzPlane(:))) * app.Grid_scaling;
                app.GyEditField_3.Value = max_dose;
            end

        end

        % Value changing function: XZSlider
        function XZSliderValueChanging(app, event)
            app.slider_current2 = round(event.Value);
            app.RowEditField.Value = app.slider_current2;
            app.YmmEditField.Value = fn_InverseIPPNormalizer(double(app.RowEditField.Value) ,double(app.Y_dicom_cm), double([0 app.NumRows]) );
            if strcmp(app.VolRotStatus, 'false')
                volume = app.DicomVolume;
            else
                volume = app.RotateDicomVolume;
            end
            app.xzPlane = fn_PlaneNavigator(volume, 'XZ', app.slider_current2, app.XZFigure, app.PixelSpacing_X, app.SliceThickness,  app.ColMap);
            if app.MaxPlaneDoseCheckBox.Value == 1
                max_dose = max(double(app.xzPlane(:))) * app.Grid_scaling;
                app.GyEditField_2.Value = max_dose;
            end
        end

        % Value changing function: XYSlider
        function XYSliderValueChanging(app, event)
            app.slider_current3 = round(event.Value);
            app.FrameEditField.Value = app.slider_current3;
            app.ZmmEditField.Value = fn_InverseIPPNormalizer(double(app.FrameEditField.Value) ,double(app.Z_dicom_cm), double([0 app.NumFrames]) );
            if strcmp(app.VolRotStatus, 'false')
                volume = app.DicomVolume;
            else
                volume = app.RotateDicomVolume;
            end
            app.xyPlane = fn_PlaneNavigator(volume, 'XY', app.slider_current3, app.XYFigure, app.PixelSpacing_X, app.PixelSpacing_Y,  app.ColMap);
            if app.MaxPlaneDoseCheckBox.Value == 1
                max_dose = max(double(app.xyPlane(:))) * app.Grid_scaling;
                app.GyEditField.Value = max_dose;
            end
        end

        % Button pushed function: CenterCropButton
        function CenterCropButtonPushed(app, event)
            Img_size = size(app.calib_film);
            target_size = [app.HeightCC.Value, app.WidthCC.Value];

            r = centerCropWindow2d(Img_size,target_size);
            app.calib_film = imcrop(app.calib_film,r);

            imshow(app.calib_film,[], 'Parent', app.UIAxes4);
            colormap(app.UIAxes4, app.ColMap);
        end

        % Button pushed function: Button_21
        function Button_8Pushed(app, event)
            [filename, pathname] = uiputfile('*.pdf', 'Save Report As');
            if isempty(filename)
                return;
            else
                prompt = 'Enter Plan ID: ';
                frac = inputdlg(prompt);
                Plan_ID = {frac(1)};
            end

            % Get the full path of the currently running script or function
            currentFile = mfilename('fullpath');

            % Extract the directory path from the full path
            [currentFolder, ~, ~] = fileparts(currentFile);

            MRN_ID = {app.DicomInfo.PatientID}; % Convert to cell array


            DTA = app.DDEditField.Value;
            DD = app.DTAmmEditField.Value;
            Compar1 = app.DropDown_8.Value;
            ShiftsX = app.XShiftsmmEditField.Value;
            ShiftsY = app.YShiftsmmEditField.Value;
            PassingRate = app.EditField_4.Value;

            fig = figure('visible','off');
            imshow(mat2gray(app.TPS_dose));
            colormap(app.ColMap);
            xlim('tight');
            ylim('tight');
            filePath1 = fullfile(currentFolder, 'TPSPlane.jpg');
            saveas(fig, filePath1);
            close(fig);

            fig = figure('visible','off');
            imshow(mat2gray(app.Film_dose));
            colormap(app.ColMap);
            xlim('tight');
            ylim('tight');
            filePath2 = fullfile(currentFolder, 'FilmPlane.jpg');
            saveas(fig, filePath2);
            close(fig);


            fig = figure('visible','off');
            plot(app.x_dis_TPS, app.xProfileTPS, 'r', 'LineWidth',1.5); % Increase line width to 2
            hold on;
            plot(app.x_dis_Film, app.xProfileFilm, 'b', 'LineWidth',1.5); % Increase line width to 2
            legend('TPS', 'Film', 'FontSize', 12); % Increase font size for legend
            title('[ X-Profile ]', 'FontSize', 18, 'FontWeight','bold'); % Increase font size for title
            xlabel(' Distance (mm)', 'FontSize', 14,'FontWeight','bold'); % Increase font size for x-axis label
            ylabel('Dose (cGy)', 'FontSize', 14,'FontWeight','bold'); % Increase font size for y-axis label
            xlim('tight');
            ylim('tight');
            % Access the current axes and set the FontSize property for the ticks
            ax = gca;
            ax.FontSize = 15; % Increase the font size of the ticks
            hold off;
            filePath3 = fullfile(currentFolder, 'XProfile.jpg');
            saveas(fig, filePath3);
            close(fig);

            fig = figure('visible','off');
            plot(app.y_dis_TPS, app.yProfileTPS, 'r', 'LineWidth',1.5); % Increase line width to 2
            hold on;
            plot(app.y_dis_Film, app.yProfileFilm, 'b', 'LineWidth',1.5); % Increase line width to 2
            legend('TPS', 'Film', 'FontSize', 12); % Increase font size for legend
            title('[ Y-Profile ]', 'FontSize', 18, 'FontWeight','bold'); % Increase font size for title
            xlabel(' Distance (mm)', 'FontSize', 14,'FontWeight','bold'); % Increase font size for x-axis label
            ylabel('Dose (cGy)', 'FontSize', 14,'FontWeight','bold'); % Increase font size for y-axis label
            xlim('tight');
            ylim('tight');
            % Access the current axes and set the FontSize property for the ticks
            ax = gca;
            ax.FontSize = 15; % Increase the font size of the ticks
            hold off;
            filePath4 = fullfile(currentFolder, 'YProfile.jpg');
            saveas(fig, filePath4);
            close(fig);

            fig = figure('visible','off');
            imshow(app.gammamap);
            colormap(app.ColMap);
            xlim('tight');
            ylim('tight');
            filePath5 = fullfile(currentFolder, 'GammaMap.jpg');

            saveas(fig, filePath5);
            close(fig);


            fn_PDFPrinter(MRN_ID, Plan_ID,filePath1, filePath2, filePath3, filePath4, ...
                DD, DTA, Compar1,  ShiftsX,ShiftsY,PassingRate,filePath5)

            msgbox('PDF Generated Sucessfuly!');

            delete TPSPlane.jpg;
            delete FilmPlane.jpg;
            delete XProfile.jpg;
            delete YProfile.jpg;
            delete GammaMap.jpg;

            %Rename the file and then move it to the desired location as per
            %user input
            movefile('MyReport.pdf', filename);
            movefile(filename, pathname);

        end

        % Button pushed function: AutoCenterButton
        function AutoCenterButtonPushed(app, event)
            app.calib_film = fn_AutoCenter(app.calib_film);
            imshow(app.calib_film, 'Parent', app.UIAxes4);
        end

        % Button pushed function: DoseButton
        function DoseButtonPushed(app, event)
            [file, path] = uigetfile({'*.txt'}, 'Please select the film dose file', app.Path);
            text_file = fullfile(path, file);
             % Initialize data cell array
            data = {5};

            % Open the file
            ID = fopen(text_file, 'r');
            if ID == -1
                error('File cannot be opened');
            end
            
            % Read specific lines for values
            for i = 1:5
                line = fgetl(ID);
                parts = strsplit(line, ':');
                value = strtrim(parts{2});
                data{i, 1} = value;
            end
            
            % Parse read data into variables
            app.TiffDPI = str2double(data{2}) * str2double(data{3});
            app.Field_X = str2double(data{4});
            app.Field_Y = str2double(data{5});

            app.HeightCC.Value = app.Field_Y;
            app.WidthCC.Value = app.Field_X;
            
            % Reset file pointer to the beginning of the file to read matrix
            frewind(ID);            
            string1 = 'Array Start:';
            string2 = ':Array End';
            startReading = false;
            Matrix = [];


            f = waitbar(0, 'Opening the dose file!');
            % Read the file again to find the matrix
            while ~feof(ID)
                currentLine = fgetl(ID);
                if contains(currentLine, string1)
                    startReading = true;
                    continue; % Skip the line containing 'Array Start:'
                elseif contains(currentLine, string2)
                    break; % Stop reading if 'Array End' is found
                end

               
                if startReading
                    waitbar(0.5, f, 'Loading dose file!');    
                    numericData = str2num(currentLine);
                    Matrix = [Matrix; numericData];
                end
            end
            waitbar(1, f, 'Done!');
            close(f);

            % Close the file
            fclose(ID);            
            app.calib_film = double(Matrix);
            % Show the image
            imshow(mat2gray(app.calib_film), 'Parent', app.UIAxes4);
            % Set colormap
            colormap(app.UIAxes4, app.ColMap);
        end

        % Button pushed function: DistanceButton
        function DistanceButtonPushed(app, event)
            ROI = drawline(app.UIAxes4, 'MarkerSize', 0.1);
            
            X1 = round(ROI.Position(1,1));
            Y1 = round(ROI.Position(1,2));
            X2 = round(ROI.Position(2,1));
            Y2 = round(ROI.Position(2,2));
            
            %Converting the position into area
            X1 = X1 * 2.54 /app.TiffDPI;
            X2 = X2 * 2.54 /app.TiffDPI;
            Y1 = Y1 * 2.54 /app.TiffDPI;
            Y2 = Y2 * 2.54 /app.TiffDPI;

            length = sqrt((Y2-Y1)^2 + (X2 - X1)^2);

            % Create a label for the ROI
            label_text = [num2str(length), ' cm'];
            label_x = X1 + 0.5 * ROI.Position(3); % X coordinate for label
            label_y = Y1 + 0.5 * ROI.Position(4); % Y coordinate for label
            text_handle = text(label_x, label_y, label_text, 'Parent', app.UIAxes4, 'Color', 'w', 'FontSize', 13, 'FontWeight', 'bold', 'EdgeColor', 'b');

            % Store the text handle in the ROI UserData for deletion later
            ROI.UserData = text_handle;

            % Set the buttondown function to allow deletion of text label
            set(text_handle, 'ButtonDownFcn', @(src, event) deleteText(app, src));
        end

        % Button pushed function: AreaButton
        function AreaButtonPushed(app, event)
           ROI = drawrectangle(app.UIAxes4, 'MarkerSize', 0.1);
            X1 = round(ROI.Position(1));
            Y1 = round(ROI.Position(2));
            X2 = round(X1 + ROI.Position(3) - 1);
            Y2 = round(Y1 + ROI.Position(4) - 1);
            
            % Converting the position into area
            width_cm = abs((X2 - X1) * 2.54 / app.TiffDPI);  % Width in cm
            height_cm = abs((Y2 - Y1) * 2.54 / app.TiffDPI); % Height in cm
        
            Area = width_cm * height_cm; % Area in square cm
        
            % Create a label for the ROI
            label_text = [num2str(Area), ' cm^2'];
            label_x = X1 + 0.5 * ROI.Position(3); % X coordinate for label
            label_y = Y1 + 0.5 * ROI.Position(4); % Y coordinate for label
            text_handle = text(label_x, label_y, label_text, 'Parent', app.UIAxes4, 'Color', 'w', 'FontSize', 13, 'FontWeight', 'bold', 'EdgeColor', 'b');
        
            % Store the text handle in the ROI UserData for deletion later
            ROI.UserData = text_handle;
        
            % Set the buttondown function to allow deletion of text label
            set(text_handle, 'ButtonDownFcn', @(src, event) deleteText(app, src));
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            if app.YZSlider.Value > 0 && app.YZSlider.Value < app.NumColumns
                app.YZSlider.Value = app.YZSlider.Value  + 1;
                app.ColumnEditField.Value = app.YZSlider.Value;
                app.XmmEditField.Value = fn_InverseIPPNormalizer(double(app.ColumnEditField.Value) ,double(app.X_dicom_cm), double([0 app.NumColumns]) );
                if strcmp(app.VolRotStatus, 'false')
                    volume = app.DicomVolume;
                else
                    volume = app.RotateDicomVolume;
                end
                app.yzPlane = fn_PlaneNavigator(volume, 'YZ', app.YZSlider.Value, app.YZFigure, app.PixelSpacing_Y, app.SliceThickness,  app.ColMap);
                if app.MaxPlaneDoseCheckBox.Value == 1
                    max_dose = max(double(app.yzPlane(:))) * app.Grid_scaling;
                    app.GyEditField_3.Value = max_dose;
                end
            else
                return;
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.YZSlider.Value = app.YZSlider.Value  -1;
            if app.YZSlider.Value >= 0 && app.YZSlider.Value <= app.NumColumns                
                app.ColumnEditField.Value = app.YZSlider.Value;
                app.XmmEditField.Value = fn_InverseIPPNormalizer(double(app.ColumnEditField.Value) ,double(app.X_dicom_cm), double([0 app.NumColumns]) );
                if strcmp(app.VolRotStatus, 'false')
                    volume = app.DicomVolume;
                else
                    volume = app.RotateDicomVolume;
                end
                app.yzPlane = fn_PlaneNavigator(volume, 'YZ', app.YZSlider.Value, app.YZFigure, app.PixelSpacing_Y, app.SliceThickness,  app.ColMap);
                if app.MaxPlaneDoseCheckBox.Value == 1
                    max_dose = max(double(app.yzPlane(:))) * app.Grid_scaling;
                    app.GyEditField_3.Value = max_dose;
                end
            else
                return;
            end            
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
           app.XZSlider.Value = app.XZSlider.Value + 1;
            if app.XZSlider.Value >= 0 && app.XZSlider.Value <= app.NumRows                
                app.RowEditField.Value = app.XZSlider.Value;
                app.YmmEditField.Value = fn_InverseIPPNormalizer(double(app.RowEditField.Value) ,double(app.Y_dicom_cm), double([0 app.NumRows]) );
                if strcmp(app.VolRotStatus, 'false')
                    volume = app.DicomVolume;
                else
                    volume = app.RotateDicomVolume;
                end
                app.xzPlane = fn_PlaneNavigator(volume, 'XZ', app.XZSlider.Value, app.XZFigure, app.PixelSpacing_X, app.SliceThickness,  app.ColMap);
                if app.MaxPlaneDoseCheckBox.Value == 1
                    max_dose = max(double(app.xzPlane(:))) * app.Grid_scaling;
                    app.GyEditField_2.Value = max_dose;
                end
            else
                return;
            end

        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.XZSlider.Value = app.XZSlider.Value - 1;
            if app.XZSlider.Value >= 0 && app.XZSlider.Value <= app.NumRows
                
                app.RowEditField.Value = app.XZSlider.Value;
                app.YmmEditField.Value = fn_InverseIPPNormalizer(double(app.RowEditField.Value) ,double(app.Y_dicom_cm), double([0 app.NumRows]) );
                if strcmp(app.VolRotStatus, 'false')
                    volume = app.DicomVolume;
                else
                    volume = app.RotateDicomVolume;
                end
                app.xzPlane = fn_PlaneNavigator(volume, 'XZ', app.XZSlider.Value, app.XZFigure, app.PixelSpacing_X, app.SliceThickness,  app.ColMap);
                if app.MaxPlaneDoseCheckBox.Value == 1
                    max_dose = max(double(app.xzPlane(:))) * app.Grid_scaling;
                    app.GyEditField_2.Value = max_dose;
                end
            else
                return;
            end
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            app.XYSlider.Value = app.XYSlider.Value + 1; 
            if app.XYSlider.Value >= 0 && app.XYSlider.Value <= app.NumFrames
                
                app.FrameEditField.Value =  app.XYSlider.Value;
                app.ZmmEditField.Value = fn_InverseIPPNormalizer(double(app.FrameEditField.Value) ,double(app.Z_dicom_cm), double([0 app.NumFrames]) );
                if strcmp(app.VolRotStatus, 'false')
                    volume = app.DicomVolume;
                else
                    volume = app.RotateDicomVolume;
                end
                app.xyPlane = fn_PlaneNavigator(volume, 'XY',  app.XYSlider.Value, app.XYFigure, app.PixelSpacing_X, app.PixelSpacing_Y,  app.ColMap);
                if app.MaxPlaneDoseCheckBox.Value == 1
                    max_dose = max(double(app.xyPlane(:))) * app.Grid_scaling;
                    app.GyEditField.Value = max_dose;
                end
             else
                 return;
             end

        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
           app.XYSlider.Value = app.XYSlider.Value -1;
            if app.XYSlider.Value >= 0 && app.XYSlider.Value <= app.NumFrames
                
                app.FrameEditField.Value =  app.XYSlider.Value;
                app.ZmmEditField.Value = fn_InverseIPPNormalizer(double(app.FrameEditField.Value) ,double(app.Z_dicom_cm), double([0 app.NumFrames]) );
                if strcmp(app.VolRotStatus, 'false')
                    volume = app.DicomVolume;
                else
                    volume = app.RotateDicomVolume;
                end
                app.xyPlane = fn_PlaneNavigator(volume, 'XY',  app.XYSlider.Value, app.XYFigure, app.PixelSpacing_X, app.PixelSpacing_Y,  app.ColMap);
                if app.MaxPlaneDoseCheckBox.Value == 1
                    max_dose = max(double(app.xyPlane(:))) * app.Grid_scaling;
                    app.GyEditField.Value = max_dose;
                end
            else
                return;
            end
        end

        % Value changed function: DropDown_9
        function DropDown_9ValueChanged(app, event)
            CompareProfileButtonPushed(app);            
        end

        % Button pushed function: JawSize
        function JawSizePushed(app, event)
           app.JawSize.Enable = "off";
           CombinedX = app.combinedX;
           CombinedY = app.combinedY;

           if isempty(CombinedY) || isempty(CombinedX)
               msgbox('Please calculate the X and Y profile first!');
               app.JawSize.Enable = "on";
               return;
           end         
           app.DialogApp =   FWHM_JawSize(app,CombinedX, CombinedY);
        end

        % Close request function: FilmDosimetryUIFigure
        function MainAppCloseRequest(app, event)
            delete(app)
            delete(app.DialogApp)            
        end

        % Button pushed function: SmoothButton
        function SmoothButtonPushed(app, event)
            app.calib_film = fn_MatrixSmooth(app.calib_film, app.SmoothDropDown_2.Value, app.SmoothWIn_2.Value);    
            imshow(mat2gray(app.calib_film), 'Parent', app.UIAxes4);
            colormap(app.UIAxes4, app.ColMap); 
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
           if ~isempty(app.DicomInfo)
            app.DialogApp =   DicomInfoApp(app, app.DicomInfo);
           else
               msgbox('No dicom file loaded!')
           end
        end

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
            app.DialogApp = StartShots(app);
        end

        % Button pushed function: UpdateButton_2
        function UpdateButton_2Pushed(app, event)
                if isempty(app.RotateDicomVolume)
                   Volume = app.DicomVolume;
                   
                else
                   Volume = app.RotateDicomVolume;
                end   
                Volume = smooth3(Volume);
                planeview = app.DropDown_3.Value;
                % Get size of the volume
                vol_size = size(Volume);
            
                % Generate meshgrid for the volume dimensions
                [x, y, z] = meshgrid(1:vol_size(2), 1:vol_size(1), 1:vol_size(3));
            
                % Clear existing axes contents
                cla(app.UIAxes10);
            
                % Calculate and plot the isosurface
                p = isosurface(x, y, z, Volume, app.ThresholdEditField.Value);
                patch(app.UIAxes10,p,"FaceColor","cyan", ...
                    "EdgeColor","none");

                % Decide which plane to display based on drop-down
                    centerX = round(vol_size(2) / 2);
                    centerY = round(vol_size(1) / 2);
                    centerZ = round(vol_size(3) / 2);
                
                    switch planeview
                        case 'XY'
                            [X, Y] = meshgrid(1:vol_size(2), 1:vol_size(1));
                             Z = centerZ * ones(size(X));
                             plane = [planeview, ' -Axial']; 
                        case 'XZ'
                            [X, Z] = meshgrid(1:vol_size(2), 1:vol_size(3));
                            Y = centerY * ones(size(X));
                             plane = [planeview, ' -Coronal']; 
                        case 'YZ'
                            [Y, Z] = meshgrid(1:vol_size(1), 1:vol_size(3) );
                            X = centerX * ones(size(Y));
                             plane = [planeview, ' -Sagittal']; 
                     end


                % Plot the plane
                hold(app.UIAxes10, 'on'); % Keep current plot
                surf(app.UIAxes10, X, Y, Z, 'FaceColor', 'red', 'FaceAlpha', 1, 'EdgeColor','red');
                hold(app.UIAxes10, 'off'); % Release the plot hold
            
                % % Enhance the view
                view(app.UIAxes10, [-120,27]);
                daspect(app.UIAxes10, [1,1,1]);
                camlight(app.UIAxes10);

                %updating the plane info
                app.PlaneLabel.Text = plane;
        end

        % Button pushed function: Button_16
        function Button_16Pushed(app, event)
            TableData = app.UITable.Data;
            app.DialogApp = CalibrationTools(app, TableData);
        end

        % Button pushed function: TPSButton
        function TPSButtonPushed(app, event)
            normalzied_data = uint32(app.TPS_dose);
            app.PlaneInfo.DosePlane = app.DropDown_3.Value;
            
            [filename, pathname] = uiputfile({'*.dcm'}, 'Please select', app.Path) ;
            if filename ~= 0
                app.Path = path;
                file_name = fullfile(pathname, filename);
                try
                    dicomwrite(normalzied_data,file_name,app.PlaneInfo, 'CreateMode', 'copy' );
                catch
                end

            else
            end

            msgbox('Export sucessful!');
        end

        % Button pushed function: FilmButton_2
        function FilmButton_2Pushed(app, event)
            saving_film = app.Film_dose;
            [filename, pathname] = uiputfile({'.txt'}, 'Please select', app.Path);

            if filename ~= 0
                app.Path = pathname;
                [~, name, ext] = fileparts(filename);
                file_name = fullfile(pathname, [name,'_DPI', num2str(app.TiffDPI), ext]);
                try
                    writematrix(saving_film, file_name );
                catch
                end
            else
            end

        msgbox('Film Dose saved!');
        end

        % Value changed function: ColorMapDropDown
        function ColorMapDropDownValueChanged(app, event)
            app.ColMap = app.ColorMapDropDown.Value;
            colormap(app.FigFilmDose, app.ColMap);
            colormap(app.FigDicomDose, app.ColMap);
            colormap(app.UIAxes6, app.ColMap);
            colormap(app.UIAxes11, app.ColMap);
        end

        % Value changed function: ColorMapDropDown_3
        function ColorMapDropDown_3ValueChanged(app, event)
            app.ColMap = app.ColorMapDropDown_3.Value;
            colormap(app.UIAxes4, app.ColMap);
        end

        % Value changed function: ColorMapDropDown_2
        function ColorMapDropDown_2ValueChanged(app, event)
            app.ColMap = app.ColorMapDropDown_2.Value;
            colormap(app.XZFigure, app.ColMap);
            colormap(app.YZFigure, app.ColMap);
            colormap(app.XYFigure, app.ColMap);
            colormap(app.OriginalFigure, app.ColMap);
            colormap(app.RotatedFigure, app.ColMap);
        end

        % Button pushed function: IMContrastButton
        function IMContrastButtonPushed(app, event)
            imshow(app.calib_film, []);
            imcontrast;
        end

        % Button pushed function: PickFilmCenterButton_2
        function PickFilmCenterButton_2Pushed(app, event)
            [Pos1, Pos2] = ProfilePoint(app, app.UIAxes4);
            Position = [Pos1, Pos2];

            % Translate and update the UI figure
            app.calib_film = fn_MatTransfor(app.calib_film, Position);
            imshow(app.calib_film, 'Parent', app.UIAxes4);
            colormap(app.UIAxes4, app.ColMap);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.FilmDosimetryUIFigure = uifigure('Visible', 'off');
            app.FilmDosimetryUIFigure.Name = 'FilmDosimetry';
            app.FilmDosimetryUIFigure.Icon = fullfile(pathToMLAPP, 'ml_resources', 'Graphics', 'IconLogo.png');
            app.FilmDosimetryUIFigure.CloseRequestFcn = createCallbackFcn(app, @MainAppCloseRequest, true);
            app.TabGroup = uitabgroup(app.FilmDosimetryUIFigure);
            app.CalibrationTab = uitab(app.TabGroup);
            app.CalibrationTab.Title = 'Calibration';
            app.Panel_28 = uipanel(app.CalibrationTab);
            app.ButtonGroup_2 = uibuttongroup(app.Panel_28);
            app.CenterButton = uiradiobutton(app.ButtonGroup_2);
            app.CenterButton.Text = 'Center';
            app.CenterButton.Value = true;
            app.ManualButton = uiradiobutton(app.ButtonGroup_2);
            app.ManualButton.Text = 'Manual';
            app.Button_19 = uibutton(app.ButtonGroup_2, 'push');
            app.Button_19.ButtonPushedFcn = createCallbackFcn(app, @Button_19Pushed, true);
            app.Button_19.Icon = fullfile(pathToMLAPP, 'ml_resources', 'Graphics', 'icons8-export-100.png');
            app.Button_19.Tooltip = {'Export X and Y Profile'};
            app.Button_19.Text = '';
            app.FilmProfileButton = uibutton(app.ButtonGroup_2, 'push');
            app.FilmProfileButton.ButtonPushedFcn = createCallbackFcn(app, @FilmProfileButtonPushed, true);
            app.FilmProfileButton.Text = 'Film Profile';
            app.NormalizeCheckBox = uicheckbox(app.ButtonGroup_2);
            app.NormalizeCheckBox.Text = 'Normalize';
            app.Panel_27 = uipanel(app.Panel_28);
            app.ROIDoseButton = uibutton(app.Panel_27, 'push');
            app.ROIDoseButton.ButtonPushedFcn = createCallbackFcn(app, @ROIDoseButtonPushed, true);
            app.ROIDoseButton.Text = 'ROI Dose:';
            app.DistanceButton = uibutton(app.Panel_27, 'push');
            app.DistanceButton.ButtonPushedFcn = createCallbackFcn(app, @DistanceButtonPushed, true);
            app.DistanceButton.Text = 'Distance';
            app.AreaButton = uibutton(app.Panel_27, 'push');
            app.AreaButton.ButtonPushedFcn = createCallbackFcn(app, @AreaButtonPushed, true);
            app.AreaButton.Text = 'Area';
            app.MeasurementLabel = uilabel(app.Panel_27);
            app.MeasurementLabel.Text = 'Measurement ';
            app.cGyEditFieldLabel = uilabel(app.Panel_27);
            app.cGyEditFieldLabel.Text = 'cGy';
            app.cGyEditField = uieditfield(app.Panel_27, 'numeric');
            app.cGyEditField.ValueDisplayFormat = '%.2f';
            app.cGyEditField.Editable = 'off';
            app.ProfileToolsLabel = uilabel(app.Panel_28);
            app.ProfileToolsLabel.Text = 'Profile Tools';
            app.Panel_29 = uipanel(app.Panel_28);
            app.AutoAlignImageButton = uibutton(app.Panel_29, 'push');
            app.AutoAlignImageButton.ButtonPushedFcn = createCallbackFcn(app, @AutoAlignImageButtonPushed, true);
            app.AutoAlignImageButton.Text = 'Auto Align Image';
            app.ManuallyAlignButton = uibutton(app.Panel_29, 'push');
            app.ManuallyAlignButton.ButtonPushedFcn = createCallbackFcn(app, @ManuallyAlignButtonPushed, true);
            app.ManuallyAlignButton.Text = 'Manually Align';
            app.ConverttodoseButton = uibutton(app.Panel_29, 'push');
            app.ConverttodoseButton.ButtonPushedFcn = createCallbackFcn(app, @ConverttodoseButtonPushed, true);
            app.ConverttodoseButton.Text = 'Convert to dose';
            app.Button_20 = uibutton(app.Panel_29, 'push');
            app.Button_20.ButtonPushedFcn = createCallbackFcn(app, @Button_20Pushed, true);
            app.Button_20.Icon = fullfile(pathToMLAPP, 'ml_resources', 'Graphics', 'icons8-export-100.png');
            app.Button_20.Tooltip = {'Export Dose'};
            app.Button_20.Text = '';
            app.UpdateFilmDoseButton = uibutton(app.Panel_29, 'push');
            app.UpdateFilmDoseButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateFilmDoseButtonPushed, true);
            app.UpdateFilmDoseButton.Text = 'Update Film Dose';
            app.PickFilmCenterButton_2 = uibutton(app.Panel_29, 'push');
            app.PickFilmCenterButton_2.ButtonPushedFcn = createCallbackFcn(app, @PickFilmCenterButton_2Pushed, true);
            app.PickFilmCenterButton_2.Text = 'Pick Film Center';
            app.AutoCenterButton = uibutton(app.Panel_29, 'push');
            app.AutoCenterButton.ButtonPushedFcn = createCallbackFcn(app, @AutoCenterButtonPushed, true);
            app.AutoCenterButton.Text = 'Auto Center';
            app.Film2DoseLabel = uilabel(app.Panel_29);
            app.Film2DoseLabel.Text = 'Film2Dose';
            app.Panel_30 = uipanel(app.Panel_28);
            app.CWButton = uibutton(app.Panel_30, 'push');
            app.CWButton.ButtonPushedFcn = createCallbackFcn(app, @CWButtonPushed, true);
            app.CWButton.Text = 'CW';
            app.CCWButton = uibutton(app.Panel_30, 'push');
            app.CCWButton.ButtonPushedFcn = createCallbackFcn(app, @CCWButtonPushed, true);
            app.CCWButton.Text = 'CCW';
            app.FilpHButton = uibutton(app.Panel_30, 'push');
            app.FilpHButton.ButtonPushedFcn = createCallbackFcn(app, @FilpHButtonPushed, true);
            app.FilpHButton.Text = 'Filp H';
            app.FlipVButton = uibutton(app.Panel_30, 'push');
            app.FlipVButton.ButtonPushedFcn = createCallbackFcn(app, @FlipVButtonPushed, true);
            app.FlipVButton.Text = 'Flip V';
            app.Panel_26 = uipanel(app.Panel_30);
            app.HeightCC = uieditfield(app.Panel_26, 'numeric');
            app.WidthCC = uieditfield(app.Panel_26, 'numeric');
            app.HeightLabel = uilabel(app.Panel_26);
            app.HeightLabel.Text = 'Height:';
            app.WidthLabel = uilabel(app.Panel_26);
            app.WidthLabel.Text = 'Width:';
            app.CenterCropButton = uibutton(app.Panel_26, 'push');
            app.CenterCropButton.ButtonPushedFcn = createCallbackFcn(app, @CenterCropButtonPushed, true);
            app.CenterCropButton.Text = 'Center Crop';
            app.ManualCropButton = uibutton(app.Panel_26, 'push');
            app.ManualCropButton.ButtonPushedFcn = createCallbackFcn(app, @ManualCropButtonPushed, true);
            app.ManualCropButton.Text = 'Manual Crop';
            app.TransformationLabel = uilabel(app.Panel_28);
            app.TransformationLabel.Text = 'Transformation';
            app.Panel_31 = uipanel(app.Panel_28);
            app.Panel_2 = uipanel(app.Panel_31);
            app.InterpolationButton = uibutton(app.Panel_2, 'push');
            app.InterpolationButton.ButtonPushedFcn = createCallbackFcn(app, @InterpolationButtonPushed, true);
            app.InterpolationButton.Text = 'Interpolation';
            app.DropDown_7 = uidropdown(app.Panel_2);
            app.DropDown_7.Items = {'linear', 'spline', 'cubic', ''};
            app.DropDown_7.Value = 'linear';
            app.EditField_5 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_5.Value = 1;
            app.Panel_22 = uipanel(app.Panel_31);
            app.SmoothButton = uibutton(app.Panel_22, 'push');
            app.SmoothButton.ButtonPushedFcn = createCallbackFcn(app, @SmoothButtonPushed, true);
            app.SmoothButton.Text = 'Smooth';
            app.SmoothWIn_2 = uieditfield(app.Panel_22, 'numeric');
            app.SmoothWIn_2.Limits = [0 Inf];
            app.SmoothWIn_2.ValueDisplayFormat = '%.0f';
            app.SmoothWIn_2.Value = 5;
            app.SmoothDropDown_2 = uidropdown(app.Panel_22);
            app.SmoothDropDown_2.Items = {'None', 'Average', 'Median', 'Gaussian', 'Lowess', 'Loess', 'Rlowess', 'Rloess'};
            app.SmoothDropDown_2.Value = 'None';
            app.MedianFilterButton = uibutton(app.Panel_31, 'push');
            app.MedianFilterButton.ButtonPushedFcn = createCallbackFcn(app, @MedianFilterButtonPushed, true);
            app.MedianFilterButton.Text = 'Median Filter';
            app.SizeEditField = uieditfield(app.Panel_31, 'numeric');
            app.SizeEditField.Value = 3;
            app.FilterNoiseButton = uibutton(app.Panel_31, 'push');
            app.FilterNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @FilterNoiseButtonPushed, true);
            app.FilterNoiseButton.Text = 'Filter Noise';
            app.FilterNoiseEditField = uieditfield(app.Panel_31, 'numeric');
            app.FilterNoiseEditField.Value = 3000;
            app.FiltersLabel = uilabel(app.Panel_31);
            app.FiltersLabel.Text = 'Filters';
            app.ContrastSlider = uislider(app.Panel_28);
            app.ContrastSlider.Limits = [0 10];
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastSliderValueChanged, true);
            app.ContrastSlider.Value = 1;
            app.Panel_14 = uipanel(app.CalibrationTab);
            app.UIAxes9_2 = uiaxes(app.Panel_14);
            title(app.UIAxes9_2, 'Y Profile')
            app.UIAxes9_2.XTick = [];
            app.UIAxes9_2.YTick = [];
            app.UIAxes9 = uiaxes(app.Panel_14);
            title(app.UIAxes9, 'X Profile')
            app.UIAxes9.XTick = [];
            app.UIAxes9.YTick = [];
            app.UITable = uitable(app.Panel_14);
            app.UITable.ColumnName = {'Dose_MU'; 'Red_channel'; 'Green_channel'; 'Blue_channel'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.ColumnEditable = true;
            app.Panel_20 = uipanel(app.CalibrationTab);
            app.UIAxes4 = uiaxes(app.Panel_20);
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4.XAxisLocation = 'origin';
            app.UIAxes4.XTick = [];
            app.UIAxes4.YAxisLocation = 'origin';
            app.UIAxes4.YTick = [];
            app.IMContrastButton = uibutton(app.Panel_20, 'push');
            app.IMContrastButton.ButtonPushedFcn = createCallbackFcn(app, @IMContrastButtonPushed, true);
            app.IMContrastButton.Text = 'IMContrast';
            app.Panel_32 = uipanel(app.CalibrationTab);
            app.DoseButton = uibutton(app.Panel_32, 'push');
            app.DoseButton.ButtonPushedFcn = createCallbackFcn(app, @DoseButtonPushed, true);
            app.DoseButton.Icon = fullfile(pathToMLAPP, 'ml_resources', 'Graphics', 'icons8-import-100.png');
            app.DoseButton.Text = 'Dose';
            app.FilmButton = uibutton(app.Panel_32, 'push');
            app.FilmButton.ButtonPushedFcn = createCallbackFcn(app, @FilmButtonPushed, true);
            app.FilmButton.Icon = fullfile(pathToMLAPP, 'ml_resources', 'Graphics', 'icons8-import-100.png');
            app.FilmButton.Text = 'Film';
            app.ColorMapDropDown_3Label = uilabel(app.Panel_32);
            app.ColorMapDropDown_3Label.Text = 'Color Map';
            app.ColorMapDropDown_3 = uidropdown(app.Panel_32);
            app.ColorMapDropDown_3.Items = {'jet', 'parula', 'hsv', 'hot', 'cool', 'spring', 'summer', 'bone', 'gray', 'sky', 'lines', 'flag', 'white', 'prism'};
            app.ColorMapDropDown_3.ValueChangedFcn = createCallbackFcn(app, @ColorMapDropDown_3ValueChanged, true);
            app.ColorMapDropDown_3.Value = 'jet';
            app.CalibrationCurveLabel = uilabel(app.Panel_32);
            app.CalibrationCurveLabel.Text = 'Calibration Curve:';
            app.NotReadyLabel = uilabel(app.Panel_32);
            app.NotReadyLabel.Text = 'Not Ready!';
            app.Panel_35 = uipanel(app.CalibrationTab);
            app.Button_18 = uibutton(app.Panel_35, 'push');
            app.Button_18.ButtonPushedFcn = createCallbackFcn(app, @Button_18Pushed, true);
            app.Button_18.Icon = fullfile(pathToMLAPP, 'ml_resources', 'Graphics', 'icons8-polaroid-64.png');
            app.Button_18.Tooltip = {'Load Calibration Films'};
            app.Button_18.Text = '';
            app.Button_17 = uibutton(app.Panel_35, 'push');
            app.Button_17.ButtonPushedFcn = createCallbackFcn(app, @Button_17Pushed, true);
            app.Button_17.Icon = fullfile(pathToMLAPP, 'ml_resources', 'Graphics', 'icons8-graph-100.png');
            app.Button_17.Tooltip = {'Create Calibration Curve'};
            app.Button_17.Text = '';
            app.Button_16 = uibutton(app.Panel_35, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @Button_16Pushed, true);
            app.Button_16.Icon = fullfile(pathToMLAPP, 'ml_resources', 'Graphics', 'icons8-wrench-100.png');
            app.Button_16.Tooltip = {'Calibration Tools'};
            app.Button_16.Text = '';
            app.CalibrationToolsLabel = uilabel(app.Panel_35);
            app.CalibrationToolsLabel.Text = 'Calibration Tools';
            app.Panel_36 = uipanel(app.CalibrationTab);
            app.Button_15 = uibutton(app.Panel_36, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Icon = fullfile(pathToMLAPP, 'ml_resources', 'Graphics', 'icons8-star-100.png');
            app.Button_15.Tooltip = {'Star Shots'};
            app.Button_15.Text = '';
            app.JawSize = uibutton(app.Panel_36, 'push');
            app.JawSize.ButtonPushedFcn = createCallbackFcn(app, @JawSizePushed, true);
            app.JawSize.Icon = fullfile(pathToMLAPP, 'ml_resources', 'Graphics', 'icons8-jaw-66.png');
            app.JawSize.Tooltip = {'Jaw Size'};
            app.JawSize.Text = '';
            app.FilmToolsLabel = uilabel(app.Panel_36);
            app.FilmToolsLabel.Text = 'Film Tools';
            app.Button_22 = uibutton(app.Panel_36, 'push');
            app.Button_22.Icon = fullfile(pathToMLAPP, 'ml_resources', 'Graphics', 'logo_jawsize1.jpg');
            app.Button_22.Tooltip = {'Synchronicity'};
            app.Button_22.Text = '';
            app.DicomDoseViewerTab = uitab(app.TabGroup);
            app.DicomDoseViewerTab.Title = 'Dicom Dose Viewer';
            app.Panel_33 = uipanel(app.DicomDoseViewerTab);
            app.RDDoseButton = uibutton(app.Panel_33, 'push');
            app.RDDoseButton.ButtonPushedFcn = createCallbackFcn(app, @RDDoseButtonPushed, true);
            app.RDDoseButton.Icon = fullfile(pathToMLAPP, 'ml_resources', 'Graphics', 'icons8-import-100.png');
            app.RDDoseButton.Tooltip = {'Import 3D RD File'};
            app.RDDoseButton.Text = 'RD Dose';
            app.Button_9 = uibutton(app.Panel_33, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Icon = fullfile(pathToMLAPP, 'ml_resources', 'Graphics', 'icons8-info-50.png');
            app.Button_9.Tooltip = {'Dicom Info'};
            app.Button_9.Text = '';
            app.MaxPlaneDoseCheckBox = uicheckbox(app.Panel_33);
            app.MaxPlaneDoseCheckBox.Text = 'Max Plane Dose';
            app.MRNLabel_2 = uilabel(app.Panel_33);
            app.MRNLabel_2.Text = 'MRN:';
            app.MRNEditField_2 = uieditfield(app.Panel_33, 'text');
            app.ColorMapDropDown_2Label = uilabel(app.Panel_33);
            app.ColorMapDropDown_2Label.Text = 'Color Map';
            app.ColorMapDropDown_2 = uidropdown(app.Panel_33);
            app.ColorMapDropDown_2.Items = {'jet', 'parula', 'hsv', 'hot', 'cool', 'spring', 'summer', 'bone', 'gray', 'sky', 'lines', 'flag', 'white', 'prism'};
            app.ColorMapDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ColorMapDropDown_2ValueChanged, true);
            app.ColorMapDropDown_2.Value = 'jet';
            app.TPSButton = uibutton(app.Panel_33, 'push');
            app.TPSButton.ButtonPushedFcn = createCallbackFcn(app, @TPSButtonPushed, true);
            app.TPSButton.Icon = fullfile(pathToMLAPP, 'ml_resources', 'Graphics', 'icons8-export-100.png');
            app.TPSButton.Text = {'TPS'; ''};
            app.DosePlaneCalculatorPanel = uipanel(app.DicomDoseViewerTab);
            app.DosePlaneCalculatorPanel.Title = 'Dose Plane Calculator';
            app.Panel_4 = uipanel(app.DosePlaneCalculatorPanel);
            app.SystemLabel = uilabel(app.Panel_4);
            app.SystemLabel.Text = 'System';
            app.XmmEditFieldLabel = uilabel(app.Panel_4);
            app.XmmEditFieldLabel.Text = 'X (mm)';
            app.XmmEditField = uieditfield(app.Panel_4, 'numeric');
            app.XmmEditField.ValueDisplayFormat = '%.2f';
            app.YmmEditFieldLabel = uilabel(app.Panel_4);
            app.YmmEditFieldLabel.Text = 'Y (mm)';
            app.YmmEditField = uieditfield(app.Panel_4, 'numeric');
            app.YmmEditField.ValueDisplayFormat = '%.2f';
            app.ZmmEditFieldLabel = uilabel(app.Panel_4);
            app.ZmmEditFieldLabel.Text = 'Z (mm)';
            app.ZmmEditField = uieditfield(app.Panel_4, 'numeric');
            app.ZmmEditField.ValueDisplayFormat = '%.2f';
            app.EditField_6 = uieditfield(app.Panel_4, 'numeric');
            app.EditField_6.ValueDisplayFormat = '%.2f';
            app.EditField_6.Editable = 'off';
            app.EditField_7 = uieditfield(app.Panel_4, 'numeric');
            app.EditField_7.ValueDisplayFormat = '%.2f';
            app.EditField_7.Editable = 'off';
            app.EditField_8 = uieditfield(app.Panel_4, 'numeric');
            app.EditField_8.ValueDisplayFormat = '%.2f';
            app.EditField_8.Editable = 'off';
            app.EditField_9 = uieditfield(app.Panel_4, 'numeric');
            app.EditField_9.ValueDisplayFormat = '%.2f';
            app.EditField_9.Editable = 'off';
            app.EditField_10 = uieditfield(app.Panel_4, 'numeric');
            app.EditField_10.ValueDisplayFormat = '%.2f';
            app.EditField_10.Editable = 'off';
            app.EditField_11 = uieditfield(app.Panel_4, 'numeric');
            app.EditField_11.ValueDisplayFormat = '%.2f';
            app.EditField_11.Editable = 'off';
            app.Label_17 = uilabel(app.Panel_4);
            app.Label_17.Text = '>';
            app.Label_18 = uilabel(app.Panel_4);
            app.Label_18.Text = '<';
            app.Label_19 = uilabel(app.Panel_4);
            app.Label_19.Text = '>';
            app.Label_20 = uilabel(app.Panel_4);
            app.Label_20.Text = '<';
            app.Label_21 = uilabel(app.Panel_4);
            app.Label_21.Text = '>';
            app.Label_22 = uilabel(app.Panel_4);
            app.Label_22.Text = '<';
            app.CalculateButton = uibutton(app.Panel_4, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate!';
            app.Panel_5 = uipanel(app.Panel_4);
            app.ColumnEditFieldLabel = uilabel(app.Panel_5);
            app.ColumnEditFieldLabel.Text = 'Column';
            app.ColumnEditField = uieditfield(app.Panel_5, 'numeric');
            app.ColumnEditField.ValueDisplayFormat = '%.0f';
            app.RowEditFieldLabel = uilabel(app.Panel_5);
            app.RowEditFieldLabel.Text = ' Row';
            app.RowEditField = uieditfield(app.Panel_5, 'numeric');
            app.RowEditField.ValueDisplayFormat = '%.0f';
            app.FrameEditFieldLabel = uilabel(app.Panel_5);
            app.FrameEditFieldLabel.Text = 'Frame';
            app.FrameEditField = uieditfield(app.Panel_5, 'numeric');
            app.FrameEditField.ValueDisplayFormat = '%.0f';
            app.Panel_7 = uipanel(app.DosePlaneCalculatorPanel);
            app.RotateButton = uibutton(app.Panel_7, 'push');
            app.RotateButton.ButtonPushedFcn = createCallbackFcn(app, @RotateButtonPushed, true);
            app.RotateButton.Text = 'Rotate';
            app.PitchEditFieldLabel = uilabel(app.Panel_7);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.Panel_7, 'numeric');
            app.RollEditFieldLabel = uilabel(app.Panel_7);
            app.RollEditFieldLabel.Text = 'Roll';
            app.RollEditField = uieditfield(app.Panel_7, 'numeric');
            app.YawEditFieldLabel = uilabel(app.Panel_7);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.YawEditField = uieditfield(app.Panel_7, 'numeric');
            app.Panel_8 = uipanel(app.DosePlaneCalculatorPanel);
            app.SelecttheplaneLabel = uilabel(app.Panel_8);
            app.SelecttheplaneLabel.Text = 'Select the plane ';
            app.DropDown_3 = uidropdown(app.Panel_8);
            app.DropDown_3.Items = {'XZ', 'YZ', 'XY'};
            app.DropDown_3.Value = 'XZ';
            app.UpdateDicomDoseButton = uibutton(app.Panel_8, 'push');
            app.UpdateDicomDoseButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateDicomDoseButtonPushed, true);
            app.UpdateDicomDoseButton.Text = 'Update Dicom Dose';
            app.RotatedDoseButton = uibutton(app.DosePlaneCalculatorPanel, 'push');
            app.RotatedDoseButton.ButtonPushedFcn = createCallbackFcn(app, @RotatedDoseButtonPushed, true);
            app.RotatedDoseButton.Icon = fullfile(pathToMLAPP, 'ml_resources', 'Graphics', 'icons8-export-100.png');
            app.RotatedDoseButton.Tooltip = {'Export Rotated Dose'};
            app.RotatedDoseButton.Text = ' Rotated Dose';
            app.Button_12 = uibutton(app.DosePlaneCalculatorPanel, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '^';
            app.Button_10 = uibutton(app.DosePlaneCalculatorPanel, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '<';
            app.vButton = uibutton(app.DosePlaneCalculatorPanel, 'push');
            app.vButton.ButtonPushedFcn = createCallbackFcn(app, @vButtonPushed, true);
            app.vButton.Text = 'v';
            app.StepsizepixelEditField = uieditfield(app.DosePlaneCalculatorPanel, 'numeric');
            app.StepsizepixelEditField.Value = 1;
            app.Button_11 = uibutton(app.DosePlaneCalculatorPanel, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '>';
            app.RotatedDoseAlignmentPanel = uipanel(app.DicomDoseViewerTab);
            app.RotatedDoseAlignmentPanel.Title = 'Rotated Dose Alignment';
            app.OriginalFigure = uiaxes(app.RotatedDoseAlignmentPanel);
            title(app.OriginalFigure, 'Original')
            app.OriginalFigure.XTick = [];
            app.OriginalFigure.YTick = [];
            app.RotatedFigure = uiaxes(app.RotatedDoseAlignmentPanel);
            title(app.RotatedFigure, 'Rotated')
            app.RotatedFigure.XTick = [];
            app.RotatedFigure.YTick = [];
            app.Label_9 = uilabel(app.RotatedDoseAlignmentPanel);
            app.Label_9.Text = '_';
            app.Label_10 = uilabel(app.RotatedDoseAlignmentPanel);
            app.Label_10.Text = '_';
            app.Label_11 = uilabel(app.RotatedDoseAlignmentPanel);
            app.Label_11.Text = '_';
            app.Label_12 = uilabel(app.RotatedDoseAlignmentPanel);
            app.Label_12.Text = '_';
            app.RotatedPlane = uidropdown(app.RotatedDoseAlignmentPanel);
            app.RotatedPlane.Items = {'XY', 'YZ', 'XZ'};
            app.RotatedPlane.ValueChangedFcn = createCallbackFcn(app, @RotatedPlaneValueChanged, true);
            app.RotatedPlane.Value = 'XY';
            app.OGPlane = uidropdown(app.RotatedDoseAlignmentPanel);
            app.OGPlane.Items = {'XY', 'YZ', 'XZ'};
            app.OGPlane.ValueChangedFcn = createCallbackFcn(app, @OGPlaneValueChanged, true);
            app.OGPlane.Value = 'XY';
            app.Panel_11 = uipanel(app.DicomDoseViewerTab);
            app.YZFigure = uiaxes(app.Panel_11);
            app.YZFigure.XAxisLocation = 'origin';
            app.YZFigure.XTick = [];
            app.YZFigure.YAxisLocation = 'origin';
            app.YZFigure.YTick = [];
            app.Button_5 = uibutton(app.Panel_11, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '>';
            app.Button_2 = uibutton(app.Panel_11, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '<';
            app.YZSlider = uislider(app.Panel_11);
            app.YZSlider.ValueChangingFcn = createCallbackFcn(app, @YZSliderValueChanging, true);
            app.GyEditField_3Label = uilabel(app.Panel_11);
            app.GyEditField_3Label.Text = '(Gy)';
            app.GyEditField_3 = uieditfield(app.Panel_11, 'numeric');
            app.Panel_12 = uipanel(app.DicomDoseViewerTab);
            app.XZFigure = uiaxes(app.Panel_12);
            app.XZFigure.XAxisLocation = 'origin';
            app.XZFigure.XTick = [];
            app.XZFigure.YAxisLocation = 'origin';
            app.XZFigure.YTick = [];
            app.Button_6 = uibutton(app.Panel_12, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '>';
            app.Button_3 = uibutton(app.Panel_12, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '<';
            app.XZSlider = uislider(app.Panel_12);
            app.XZSlider.ValueChangingFcn = createCallbackFcn(app, @XZSliderValueChanging, true);
            app.GyEditField_2Label = uilabel(app.Panel_12);
            app.GyEditField_2Label.Text = '(Gy)';
            app.GyEditField_2 = uieditfield(app.Panel_12, 'numeric');
            app.Panel_13 = uipanel(app.DicomDoseViewerTab);
            app.XYFigure = uiaxes(app.Panel_13);
            app.XYFigure.XAxisLocation = 'origin';
            app.XYFigure.XTick = [];
            app.XYFigure.YAxisLocation = 'origin';
            app.XYFigure.YTick = [];
            app.Button_7 = uibutton(app.Panel_13, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '>';
            app.Button_4 = uibutton(app.Panel_13, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '<';
            app.XYSlider = uislider(app.Panel_13);
            app.XYSlider.ValueChangingFcn = createCallbackFcn(app, @XYSliderValueChanging, true);
            app.GyEditFieldLabel = uilabel(app.Panel_13);
            app.GyEditFieldLabel.Text = '(Gy)';
            app.GyEditField = uieditfield(app.Panel_13, 'numeric');
            app.Panel_25 = uipanel(app.DicomDoseViewerTab);
            app.UIAxes10 = uiaxes(app.Panel_25);
            xlabel(app.UIAxes10, 'X')
            ylabel(app.UIAxes10, 'Y')
            zlabel(app.UIAxes10, 'Z')
            app.ThresholdEditFieldLabel = uilabel(app.Panel_25);
            app.ThresholdEditFieldLabel.Text = 'Threshold:';
            app.ThresholdEditField = uieditfield(app.Panel_25, 'numeric');
            app.PlaneLabel = uilabel(app.Panel_25);
            app.PlaneLabel.Text = 'Plane';
            app.UpdateButton_2 = uibutton(app.Panel_25, 'push');
            app.UpdateButton_2.ButtonPushedFcn = createCallbackFcn(app, @UpdateButton_2Pushed, true);
            app.UpdateButton_2.Text = 'Update';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.Label_15 = uilabel(app.AnalysisTab);
            app.Label_15.Text = '';
            app.Label_16 = uilabel(app.AnalysisTab);
            app.Label_16.Text = '';
            app.DoseandProfileToolsPanel = uipanel(app.AnalysisTab);
            app.DoseandProfileToolsPanel.Title = 'Dose and Profile Tools';
            app.ScaleTPSButton = uibutton(app.DoseandProfileToolsPanel, 'push');
            app.ScaleTPSButton.ButtonPushedFcn = createCallbackFcn(app, @ScaleTPSButtonPushed, true);
            app.ScaleTPSButton.Text = 'Scale TPS';
            app.FilmCheckBox = uicheckbox(app.DoseandProfileToolsPanel);
            app.FilmCheckBox.Text = 'Film';
            app.TPSCheckBox = uicheckbox(app.DoseandProfileToolsPanel);
            app.TPSCheckBox.Text = 'TPS';
            app.CropButton = uibutton(app.DoseandProfileToolsPanel, 'push');
            app.CropButton.ButtonPushedFcn = createCallbackFcn(app, @CropButtonPushed, true);
            app.CropButton.Text = 'Crop';
            app.DPIEditFieldLabel = uilabel(app.DoseandProfileToolsPanel);
            app.DPIEditFieldLabel.Text = 'DPI';
            app.DPIEditField = uieditfield(app.DoseandProfileToolsPanel, 'numeric');
            app.FixFilmResButton = uibutton(app.DoseandProfileToolsPanel, 'push');
            app.FixFilmResButton.ButtonPushedFcn = createCallbackFcn(app, @FixFilmResButtonPushed, true);
            app.FixFilmResButton.Text = 'Fix Film Res';
            app.FxEditFieldLabel = uilabel(app.DoseandProfileToolsPanel);
            app.FxEditFieldLabel.Text = 'Fx';
            app.FxEditField = uieditfield(app.DoseandProfileToolsPanel, 'numeric');
            app.FxEditField.Value = 10;
            app.ProfileButton = uibutton(app.DoseandProfileToolsPanel, 'push');
            app.ProfileButton.ButtonPushedFcn = createCallbackFcn(app, @ProfileButtonPushed, true);
            app.ProfileButton.Text = 'Profile';
            app.ScaleVal = uieditfield(app.DoseandProfileToolsPanel, 'numeric');
            app.ScaleVal.Limits = [0.1 2];
            app.ScaleVal.ValueDisplayFormat = '%.2f';
            app.ScaleVal.Value = 1;
            app.CompareProfileButton = uibutton(app.DoseandProfileToolsPanel, 'push');
            app.CompareProfileButton.ButtonPushedFcn = createCallbackFcn(app, @CompareProfileButtonPushed, true);
            app.CompareProfileButton.Text = 'Compare Profile';
            app.AlignmentPanel = uipanel(app.AnalysisTab);
            app.AlignmentPanel.Title = 'Alignment';
            app.DropDown_2 = uidropdown(app.AlignmentPanel);
            app.DropDown_2.Items = {'Film', 'TPS'};
            app.DropDown_2.Value = 'Film';
            app.LocateCenterButton = uibutton(app.AlignmentPanel, 'push');
            app.LocateCenterButton.ButtonPushedFcn = createCallbackFcn(app, @LocateCenterButtonPushed, true);
            app.LocateCenterButton.Text = 'Locate Center';
            app.Panel_10 = uipanel(app.AlignmentPanel);
            app.LeftButton_2 = uibutton(app.Panel_10, 'push');
            app.LeftButton_2.ButtonPushedFcn = createCallbackFcn(app, @LeftButton_2Pushed, true);
            app.LeftButton_2.Text = 'Left';
            app.RightButton_2 = uibutton(app.Panel_10, 'push');
            app.RightButton_2.ButtonPushedFcn = createCallbackFcn(app, @RightButton_2Pushed, true);
            app.RightButton_2.Text = 'Right';
            app.UpButton_2 = uibutton(app.Panel_10, 'push');
            app.UpButton_2.ButtonPushedFcn = createCallbackFcn(app, @UpButton_2Pushed, true);
            app.UpButton_2.Text = 'Up';
            app.DownButton_2 = uibutton(app.Panel_10, 'push');
            app.DownButton_2.ButtonPushedFcn = createCallbackFcn(app, @DownButton_2Pushed, true);
            app.DownButton_2.Text = 'Down';
            app.StepsizepixelEditField_2 = uieditfield(app.Panel_10, 'numeric');
            app.StepsizepixelEditField_2.ValueDisplayFormat = '%.1f';
            app.StepsizepixelEditField_2.Value = 1;
            app.PerformGammaCheckBox = uicheckbox(app.AlignmentPanel);
            app.PerformGammaCheckBox.Text = 'Perform Gamma';
            app.Panel_16 = uipanel(app.AnalysisTab);
            app.FigDicomDose = uiaxes(app.Panel_16);
            app.FigDicomDose.XAxisLocation = 'top';
            app.FigDicomDose.XTick = [];
            app.FigDicomDose.YAxisLocation = 'origin';
            app.FigDicomDose.YTick = [];
            app.TPSSlider = uislider(app.Panel_16);
            app.TPSSlider.ValueChangedFcn = createCallbackFcn(app, @TPSSliderValueChanged, true);
            app.PlanDoseButton = uibutton(app.Panel_16, 'push');
            app.PlanDoseButton.ButtonPushedFcn = createCallbackFcn(app, @PlanDoseButtonPushed, true);
            app.PlanDoseButton.Icon = fullfile(pathToMLAPP, 'ml_resources', 'Graphics', 'icons8-import-100.png');
            app.PlanDoseButton.Text = 'Plan Dose';
            app.Panel_17 = uipanel(app.AnalysisTab);
            app.FigFilmDose = uiaxes(app.Panel_17);
            zlabel(app.FigFilmDose, 'Z')
            app.FigFilmDose.XAxisLocation = 'top';
            app.FigFilmDose.XTick = [];
            app.FigFilmDose.YAxisLocation = 'origin';
            app.FigFilmDose.YTick = [];
            app.FilmDoseButton = uibutton(app.Panel_17, 'push');
            app.FilmDoseButton.ButtonPushedFcn = createCallbackFcn(app, @FilmDoseButtonPushed, true);
            app.FilmDoseButton.Icon = fullfile(pathToMLAPP, 'ml_resources', 'Graphics', 'icons8-import-100.png');
            app.FilmDoseButton.Text = 'Film Dose';
            app.FilmSlider = uislider(app.Panel_17);
            app.FilmSlider.ValueChangedFcn = createCallbackFcn(app, @FilmSliderValueChanged2, true);
            app.Panel_18 = uipanel(app.AnalysisTab);
            app.UIAxes2 = uiaxes(app.Panel_18);
            title(app.UIAxes2, 'X-Profile ')
            ylabel(app.UIAxes2, 'Dose (cGy)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.Panel_18);
            title(app.UIAxes3, 'Y-Profile ')
            ylabel(app.UIAxes3, 'Dose (cGy)')
            zlabel(app.UIAxes3, 'Z')
            app.Panel_21 = uipanel(app.Panel_18);
            app.DropDown_9 = uidropdown(app.Panel_21);
            app.DropDown_9.Items = {'Mean', 'Median', 'Max'};
            app.DropDown_9.ValueChangedFcn = createCallbackFcn(app, @DropDown_9ValueChanged, true);
            app.DropDown_9.Value = 'Max';
            app.XDDEditFieldLabel = uilabel(app.Panel_21);
            app.XDDEditFieldLabel.Text = 'X: DD (%)';
            app.XDDEditField = uieditfield(app.Panel_21, 'numeric');
            app.DDLabel_2 = uilabel(app.Panel_21);
            app.DDLabel_2.Text = 'Y: DD (%)';
            app.YDDEditField = uieditfield(app.Panel_21, 'numeric');
            app.Panel_19 = uipanel(app.AnalysisTab);
            app.UIAxes6 = uiaxes(app.Panel_19);
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes6.XTick = [];
            app.UIAxes6.YTick = [];
            app.UIAxes6.ZTick = [];
            app.ResultPanel = uipanel(app.Panel_19);
            app.ResultPanel.Title = 'Result';
            app.UIAxes11 = uiaxes(app.ResultPanel);
            app.XShiftsmmEditFieldLabel = uilabel(app.ResultPanel);
            app.XShiftsmmEditFieldLabel.Text = 'X Shifts (mm)';
            app.XShiftsmmEditField = uieditfield(app.ResultPanel, 'numeric');
            app.XShiftsmmEditField.ValueDisplayFormat = '%.2f';
            app.YShiftsmmEditFieldLabel = uilabel(app.ResultPanel);
            app.YShiftsmmEditFieldLabel.Text = 'Y Shifts (mm)';
            app.YShiftsmmEditField = uieditfield(app.ResultPanel, 'numeric');
            app.YShiftsmmEditField.ValueDisplayFormat = '%.2f';
            app.EditField_3 = uieditfield(app.ResultPanel, 'numeric');
            app.EditField_3.Editable = 'off';
            app.EditField2 = uieditfield(app.ResultPanel, 'numeric');
            app.EditField2.Editable = 'off';
            app.DDDTALabel = uilabel(app.ResultPanel);
            app.DDDTALabel.Text = '(DD/DTA)';
            app.Label_13 = uilabel(app.ResultPanel);
            app.Label_13.Text = '%';
            app.EditField_4 = uieditfield(app.ResultPanel, 'numeric');
            app.EditField_4.Editable = 'off';
            app.DoseScaledLabel = uilabel(app.ResultPanel);
            app.DoseScaledLabel.Text = 'Dose Scaled( %)';
            app.DoseScaledEditField = uieditfield(app.ResultPanel, 'numeric');
            app.DoseScaledEditField.ValueDisplayFormat = '%.2f';
            app.GammaToolsPanel = uipanel(app.Panel_19);
            app.GammaToolsPanel.Title = 'Gamma Tools';
            app.DDLabel = uilabel(app.GammaToolsPanel);
            app.DDLabel.Text = 'DD (%):';
            app.DDEditField = uieditfield(app.GammaToolsPanel, 'numeric');
            app.DDEditField.Value = 2;
            app.DTAmmLabel = uilabel(app.GammaToolsPanel);
            app.DTAmmLabel.Text = 'DTA (mm):';
            app.DTAmmEditField = uieditfield(app.GammaToolsPanel, 'numeric');
            app.DTAmmEditField.Value = 2;
            app.GammaEvalButton = uibutton(app.GammaToolsPanel, 'push');
            app.GammaEvalButton.ButtonPushedFcn = createCallbackFcn(app, @GammaEvalButtonPushed, true);
            app.GammaEvalButton.Text = 'Gamma Eval';
            app.PlanFilmButton = uibutton(app.GammaToolsPanel, 'push');
            app.PlanFilmButton.ButtonPushedFcn = createCallbackFcn(app, @PlanFilmButtonPushed, true);
            app.PlanFilmButton.Text = 'Plan - Film';
            app.ResLabel = uilabel(app.GammaToolsPanel);
            app.ResLabel.Text = 'Res:';
            app.ResEditField = uieditfield(app.GammaToolsPanel, 'numeric');
            app.ResEditField.Value = 5;
            app.ButtonGroup = uibuttongroup(app.GammaToolsPanel);
            app.GlobalButton = uiradiobutton(app.ButtonGroup);
            app.GlobalButton.Text = 'Global';
            app.GlobalButton.Value = true;
            app.LocalButton = uiradiobutton(app.ButtonGroup);
            app.LocalButton.Text = 'Local';
            app.DropDown_8 = uidropdown(app.ButtonGroup);
            app.DropDown_8.Items = {'Absolute', 'Relative'};
            app.DropDown_8.Value = 'Absolute';
            app.SingalLabel = uilabel(app.GammaToolsPanel);
            app.SingalLabel.Text = 'Singal (%) >=';
            app.SingalEditField = uieditfield(app.GammaToolsPanel, 'numeric');
            app.GridLabel = uilabel(app.GammaToolsPanel);
            app.GridLabel.Text = 'Grid:';
            app.GridDropDown = uidropdown(app.GammaToolsPanel);
            app.GridDropDown.Items = {'Coarse', 'Fine', ''};
            app.GridDropDown.Value = 'Coarse';
            app.SmoothLabel = uilabel(app.GammaToolsPanel);
            app.SmoothLabel.Text = 'Smooth:';
            app.SmoothDropDown = uidropdown(app.GammaToolsPanel);
            app.SmoothDropDown.Items = {'None', 'Average', 'Median', 'Gaussian', 'Lowess', 'Loess', 'Rlowess', 'Rloess'};
            app.SmoothDropDown.Value = 'None';
            app.SmoothWIn = uieditfield(app.GammaToolsPanel, 'numeric');
            app.SmoothWIn.Limits = [0 Inf];
            app.SmoothWIn.ValueDisplayFormat = '%.0f';
            app.SmoothWIn.Value = 5;
            app.Panel_34 = uipanel(app.AnalysisTab);
            app.Button_21 = uibutton(app.Panel_34, 'push');
            app.Button_21.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_21.Icon = fullfile(pathToMLAPP, 'ml_resources', 'Graphics', 'icons8-pdf-100.png');
            app.Button_21.Text = '';
            app.FilmButton_2 = uibutton(app.Panel_34, 'push');
            app.FilmButton_2.ButtonPushedFcn = createCallbackFcn(app, @FilmButton_2Pushed, true);
            app.FilmButton_2.Icon = fullfile(pathToMLAPP, 'ml_resources', 'Graphics', 'icons8-export-100.png');
            app.FilmButton_2.Text = 'Film';
            app.MRNLabel = uilabel(app.Panel_34);
            app.MRNLabel.Text = 'MRN:';
            app.MRNEditField = uieditfield(app.Panel_34, 'text');
            app.PlanLabel = uilabel(app.Panel_34);
            app.PlanLabel.Text = 'Plan:';
            app.AllFileName = uieditfield(app.Panel_34, 'text');
            app.ColorMapDropDownLabel = uilabel(app.Panel_34);
            app.ColorMapDropDownLabel.Text = 'Color Map';
            app.ColorMapDropDown = uidropdown(app.Panel_34);
            app.ColorMapDropDown.Items = {'jet', 'parula', 'hsv', 'hot', 'cool', 'spring', 'summer', 'bone', 'gray', 'sky', 'lines', 'flag', 'white', 'prism'};
            app.ColorMapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorMapDropDownValueChanged, true);
            app.ColorMapDropDown.Value = 'jet';
            % Show the figure after all components are created
            app.FilmDosimetryUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FilmDosimetry

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FilmDosimetryUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FilmDosimetryUIFigure)
        end
    end
end